classdef initView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MVPAlabtoolboxUIFigure          matlab.ui.Figure
        MVPAlabToolboxLabel             matlab.ui.control.Label
        NewStudyTabGroup                matlab.ui.container.TabGroup
        MultivariatePatternAnalysisTab  matlab.ui.container.Tab
        ConfigureButton                 matlab.ui.control.Button
        StudylocationEditFieldLabel     matlab.ui.control.Label
        StudylocationEditField          matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
        LoadpreviousstudyPanel          matlab.ui.container.Panel
        Button_2                        matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        OpenstudyfileLabel              matlab.ui.control.Label
        OpenstudyfileEditField          matlab.ui.control.EditField
        ExitButton                      matlab.ui.control.Button
        CreatenewstudyLabel             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        versionLabel                    matlab.ui.control.Label
        PlotresultsPanel                matlab.ui.container.Panel
        Button_3                        matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        OpenstudyfileLabel_2            matlab.ui.control.Label
        OpenstudyfileEditField_2        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        cfg struct;
    end
    
    properties (Access = private)
        studyView % Study configuration app
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cfg = mvpalab_init();
            app.versionLabel.Text = app.cfg.version;
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.ConfigureButton.Enable = 'off';
            app.studyView = studyView(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: Button
        function StudyLocationButtonPushed(app, event)
            openDir = fullfile(getenv('HOME'),'Desktop');
            app.cfg.studyLocation = uigetdir(openDir,'Select study folder');
            if app.cfg.studyLocation
                if isfile([app.cfg.studyLocation '/cfg.mat'])
                    warndlg(['This folder already contains study related ' ...
                        'files. Please select an empty folder.'],'Warning');
                else
                    app.StudylocationEditField.Value = app.cfg.studyLocation;
                    app.ConfigureButton.Enable = 'on';
                    cfgFile = app.cfg;
                    save([app.cfg.studyLocation '/cfg.mat'],'cfgFile');
                end
            end
        end

        % Callback function: ExitButton, MVPAlabtoolboxUIFigure
        function MVPAlabtoolboxUIFigureCloseRequest(app, event)
            delete(app.studyView);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MVPAlabtoolboxUIFigure = uifigure('Visible', 'off');
            app.MVPAlabtoolboxUIFigure.Name = 'MVPAlab toolbox';
            app.MVPAlabtoolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @MVPAlabtoolboxUIFigureCloseRequest, true);
            app.MVPAlabToolboxLabel = uilabel(app.MVPAlabtoolboxUIFigure);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.NewStudyTabGroup = uitabgroup(app.MVPAlabtoolboxUIFigure);
            app.MultivariatePatternAnalysisTab = uitab(app.NewStudyTabGroup);
            app.MultivariatePatternAnalysisTab.Title = 'Multivariate Pattern Analysis';
            app.ConfigureButton = uibutton(app.MultivariatePatternAnalysisTab, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Enable = 'off';
            app.ConfigureButton.Text = 'Configure...';
            app.StudylocationEditFieldLabel = uilabel(app.MultivariatePatternAnalysisTab);
            app.StudylocationEditFieldLabel.Text = 'Study location:';
            app.StudylocationEditField = uieditfield(app.MultivariatePatternAnalysisTab, 'text');
            app.StudylocationEditField.Editable = 'off';
            app.Button = uibutton(app.MultivariatePatternAnalysisTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StudyLocationButtonPushed, true);
            app.Button.Text = '...';
            app.LoadpreviousstudyPanel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.LoadpreviousstudyPanel.Title = 'Load previous study:';
            app.Button_2 = uibutton(app.LoadpreviousstudyPanel, 'push');
            app.Button_2.Text = '...';
            app.LoadButton = uibutton(app.LoadpreviousstudyPanel, 'push');
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.OpenstudyfileLabel = uilabel(app.LoadpreviousstudyPanel);
            app.OpenstudyfileLabel.Text = 'Open study file:';
            app.OpenstudyfileEditField = uieditfield(app.LoadpreviousstudyPanel, 'text');
            app.OpenstudyfileEditField.Editable = 'off';
            app.ExitButton = uibutton(app.MVPAlabtoolboxUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @MVPAlabtoolboxUIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.CreatenewstudyLabel = uilabel(app.MVPAlabtoolboxUIFigure);
            app.CreatenewstudyLabel.Text = 'Create new study:';
            app.Image = uiimage(app.MVPAlabtoolboxUIFigure);
            app.Image.ImageSource = 'iconmvpa.png';
            app.versionLabel = uilabel(app.MVPAlabtoolboxUIFigure);
            app.versionLabel.Text = 'version';
            app.PlotresultsPanel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.PlotresultsPanel.Title = 'Plot results:';
            app.Button_3 = uibutton(app.PlotresultsPanel, 'push');
            app.Button_3.Text = '...';
            app.LoadButton_2 = uibutton(app.PlotresultsPanel, 'push');
            app.LoadButton_2.Enable = 'off';
            app.LoadButton_2.Text = 'Load';
            app.OpenstudyfileLabel_2 = uilabel(app.PlotresultsPanel);
            app.OpenstudyfileLabel_2.Text = 'Open study file:';
            app.OpenstudyfileEditField_2 = uieditfield(app.PlotresultsPanel, 'text');
            app.OpenstudyfileEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.MVPAlabtoolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MVPAlabtoolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MVPAlabtoolboxUIFigure)
        end
    end
end