classdef initView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MVPAlabtoolboxUIFigure       matlab.ui.Figure
        NewStudyTabGroup             matlab.ui.container.TabGroup
        InitialconfigurationTab      matlab.ui.container.Tab
        ConfigureMVPAButton          matlab.ui.control.Button
        StudylocationEditFieldLabel  matlab.ui.control.Label
        StudyLocationMVPAField       matlab.ui.control.EditField
        StudyLocationMVPAButton      matlab.ui.control.Button
        AnalysistypeLabel            matlab.ui.control.Label
        AnalysistypeDropDown         matlab.ui.control.DropDown
        LoadPreviousStudyPanel       matlab.ui.container.Panel
        SelectStudyButton            matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        OpenstudyfileLabel           matlab.ui.control.Label
        OpenStudyFileEditField       matlab.ui.control.EditField
        ExitButton                   matlab.ui.control.Button
        CreatenewstudyLabel          matlab.ui.control.Label
        PlotresultsPanel             matlab.ui.container.Panel
        Button_3                     matlab.ui.control.Button
        LoadResultButton             matlab.ui.control.Button
        OpenstudyfileLabel_2         matlab.ui.control.Label
        OpenstudyfileEditField_2     matlab.ui.control.EditField
        Panel                        matlab.ui.container.Panel
        MVPALogo                     matlab.ui.control.Image
        versionLabel                 matlab.ui.control.Label
        MVPAlabToolboxLabel          matlab.ui.control.Label
    end

    properties (Access = public)
        cfg struct; % Study configuration file
        studyView   % Study configuration app
        isNew;
    end
    methods (Access = private)
        function getFocus(app)
            app.MVPAlabtoolboxUIFigure.Visible = 'off';
            app.MVPAlabtoolboxUIFigure.Visible = 'on';
        end
        
        function savecfgFile(app)
            cfg = app.cfg;
            save([app.cfg.study.studyLocation filesep 'cfg.mat'],'cfg');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cfg = mvpalab_init();
            app.versionLabel.Text = app.cfg.version;
        end

        % Callback function
        function exitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: StudyLocationMVPAButton
        function studyLocationButtonPushed(app, event)
            % Folder selector
            app.cfg = mvpalab_init();
            openDir = fullfile(getenv('HOME'),'Desktop');
            app.cfg.study.studyLocation = uigetdir(openDir,'Select folder');
            app.getFocus();
            
            if app.cfg.study.studyLocation
                % If a previous study exists, select another location:
                if isfile([app.cfg.study.studyLocation '/cfg.mat'])
                    % Show warning and disable conf button:
                    warndlg(['This folder already contains a study ' ...
                        'file. Please select an empty folder.'],'Warning');
                    app.ConfigureMVPAButton.Enable = 'off';
                    app.cfg.study.studyLocation = 0;
                else
                    % Update text field:
                    app.StudyLocationMVPAField.Value = ...
                        app.cfg.study.studyLocation;
                    
                    app.cfg.sf.filesLocation = ...
                        [app.cfg.study.studyLocation filesep 'sf'];
                    
                    % Enable conf button:
                    app.ConfigureMVPAButton.Enable = 'on';
                    
                    % Update analysis type:
                    app.cfg.study.analysis = app.AnalysistypeDropDown.Value;
                    
                    % Save the cfg file:
                    app.savecfgFile();
                end
            else
                app.StudyLocationMVPAField.Value = '';
                app.ConfigureMVPAButton.Enable = 'off';
            end
        end

        % Button pushed function: ConfigureMVPAButton
        function configureButtonPushed(app, event)
            % Update gui
            app.LoadButton.Enable = 'off';
            app.ConfigureMVPAButton.Enable = 'off';
            app.LoadResultButton.Enable = 'off';
            app.StudyLocationMVPAField.Value = '';
            
            % Load study:
            app.isNew = 1;
            app.studyView = studyView(app);
        end

        % Button pushed function: SelectStudyButton
        function selectStudyButtonPushed(app, event)
            % File selector:
            openDir = fullfile(getenv('HOME'),'Desktop');
            [file,path] = uigetfile([openDir '/cfg.mat'], ...
                'Select a cfg.mat file');
            app.getFocus();
            
            if file
                if strcmp(file,'cfg.mat')
                    % Load study file:
                    temp = load([path file]);
                    
                    % Update cfg file:
                    app.cfg = temp.cfg;
                    
                    % Update text field,file location and enable load button:
                    app.cfg.study.studyLocation = path;
                    app.OpenStudyFileEditField.Value = [path file];
                    app.LoadButton.Enable = 'on';
                    
                    % Save the cfg file:
                    app.savecfgFile();
                    
                else
                    % Show warning and disable load button
                    warndlg(['Please select a valid cfg.mat file.'],'Warning');
                    app.LoadButton.Enable = 'off';
                end
            else
                app.LoadButton.Enable = 'off';
                app.OpenStudyFileEditField.Value = '';
            end
        end

        % Button pushed function: LoadButton
        function loadButtonPushed(app, event)
            % Update gui buttons:
            app.LoadButton.Enable = 'off';
            app.ConfigureMVPAButton.Enable = 'off';
            app.LoadResultButton.Enable = 'off';
            
            % Reload study:
            temp = load([app.cfg.study.studyLocation filesep 'cfg.mat']);
            app.cfg = temp.cfg;
            
            % Start studyView:
            app.isNew = 0;
            app.studyView = studyView(app);
        end

        % Callback function: ExitButton, MVPAlabtoolboxUIFigure
        function MVPAlabtoolboxUIFigureCloseRequest(app, event)
            delete(app.studyView);
            delete(app);
        end

        % Value changed function: AnalysistypeDropDown
        function AnalysistypeDropDownValueChanged(app, event)
            app.cfg.study.analysis = app.AnalysistypeDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MVPAlabtoolboxUIFigure = uifigure('Visible', 'off');
            app.MVPAlabtoolboxUIFigure.Name = 'MVPAlab toolbox';
            app.MVPAlabtoolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @MVPAlabtoolboxUIFigureCloseRequest, true);
            app.NewStudyTabGroup = uitabgroup(app.MVPAlabtoolboxUIFigure);
            app.InitialconfigurationTab = uitab(app.NewStudyTabGroup);
            app.InitialconfigurationTab.Title = 'Initial configuration';
            app.ConfigureMVPAButton = uibutton(app.InitialconfigurationTab, 'push');
            app.ConfigureMVPAButton.ButtonPushedFcn = createCallbackFcn(app, @configureButtonPushed, true);
            app.ConfigureMVPAButton.Enable = 'off';
            app.ConfigureMVPAButton.Text = 'Configure...';
            app.StudylocationEditFieldLabel = uilabel(app.InitialconfigurationTab);
            app.StudylocationEditFieldLabel.Text = 'Study location:';
            app.StudyLocationMVPAField = uieditfield(app.InitialconfigurationTab, 'text');
            app.StudyLocationMVPAField.Editable = 'off';
            app.StudyLocationMVPAButton = uibutton(app.InitialconfigurationTab, 'push');
            app.StudyLocationMVPAButton.ButtonPushedFcn = createCallbackFcn(app, @studyLocationButtonPushed, true);
            app.StudyLocationMVPAButton.Text = '...';
            app.AnalysistypeLabel = uilabel(app.InitialconfigurationTab);
            app.AnalysistypeLabel.Text = 'Analysis type:';
            app.AnalysistypeDropDown = uidropdown(app.InitialconfigurationTab);
            app.AnalysistypeDropDown.Items = {'Multivariate Patter Analysis (MVPA)', 'Multivariate Cross-Classification (MVCC)'};
            app.AnalysistypeDropDown.ItemsData = {'MVPA', 'MVCC'};
            app.AnalysistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysistypeDropDownValueChanged, true);
            app.AnalysistypeDropDown.Value = 'MVPA';
            app.LoadPreviousStudyPanel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.LoadPreviousStudyPanel.Title = 'Load previous study:';
            app.SelectStudyButton = uibutton(app.LoadPreviousStudyPanel, 'push');
            app.SelectStudyButton.ButtonPushedFcn = createCallbackFcn(app, @selectStudyButtonPushed, true);
            app.SelectStudyButton.Text = '...';
            app.LoadButton = uibutton(app.LoadPreviousStudyPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.OpenstudyfileLabel = uilabel(app.LoadPreviousStudyPanel);
            app.OpenstudyfileLabel.Text = 'Open study file:';
            app.OpenStudyFileEditField = uieditfield(app.LoadPreviousStudyPanel, 'text');
            app.OpenStudyFileEditField.Editable = 'off';
            app.ExitButton = uibutton(app.MVPAlabtoolboxUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @MVPAlabtoolboxUIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.CreatenewstudyLabel = uilabel(app.MVPAlabtoolboxUIFigure);
            app.CreatenewstudyLabel.Text = 'Create new study:';
            app.PlotresultsPanel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.PlotresultsPanel.Title = 'Plot results:';
            app.Button_3 = uibutton(app.PlotresultsPanel, 'push');
            app.Button_3.Text = '...';
            app.LoadResultButton = uibutton(app.PlotresultsPanel, 'push');
            app.LoadResultButton.Enable = 'off';
            app.LoadResultButton.Text = 'Load';
            app.OpenstudyfileLabel_2 = uilabel(app.PlotresultsPanel);
            app.OpenstudyfileLabel_2.Text = 'Open study file:';
            app.OpenstudyfileEditField_2 = uieditfield(app.PlotresultsPanel, 'text');
            app.OpenstudyfileEditField_2.Editable = 'off';
            app.Panel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.MVPALogo = uiimage(app.Panel);
            app.MVPALogo.ImageSource = 'mvpalab.png';
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            % Show the figure after all components are created
            app.MVPAlabtoolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MVPAlabtoolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MVPAlabtoolboxUIFigure)
        end
    end
end