classdef resultView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        resultViewFigure            matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        MVPALogo                    matlab.ui.control.Image
        versionLabel                matlab.ui.control.Label
        MVPAlabToolboxLabel         matlab.ui.control.Label
        resultFilePanel             matlab.ui.container.Panel
        loadResultButton            matlab.ui.control.Button
        ResultfileLabel             matlab.ui.control.Label
        loadResultField             matlab.ui.control.EditField
        plotResutsButton            matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        BasicconfigurationTab       matlab.ui.container.Tab
        ErrormetricLabel            matlab.ui.control.Label
        ErrormetricDropDown         matlab.ui.control.DropDown
        SmoothdataSpinnerLabel      matlab.ui.control.Label
        SmoothdataSpinner           matlab.ui.control.Spinner
        ShadealphaLabel             matlab.ui.control.Label
        ShadealphaSpinner           matlab.ui.control.Spinner
        LinestyleDropDownLabel      matlab.ui.control.Label
        LinestyleDropDown           matlab.ui.control.DropDown
        ShadecolorDropDownLabel     matlab.ui.control.Label
        ShadecolorDropDown          matlab.ui.control.DropDown
        inverColorScheme            matlab.ui.control.CheckBox
        ColorschemeLabel            matlab.ui.control.Label
        colorSchemeSelector         matlab.ui.control.DropDown
        TemporalgeneralizationTab   matlab.ui.container.Tab
        EndtimeEditField_3          matlab.ui.control.NumericEditField
        CAXISEditFieldLabel         matlab.ui.control.Label
        CAXISEditField              matlab.ui.control.NumericEditField
        StatisticsTab               matlab.ui.container.Tab
        plotAboveCluster            matlab.ui.control.CheckBox
        plotBelowCluster            matlab.ui.control.CheckBox
        HighlightmodeDropDownLabel  matlab.ui.control.Label
        HighlightmodeDropDown       matlab.ui.control.DropDown
        PointspositionLabel         matlab.ui.control.Label
        PointspositionSpinner       matlab.ui.control.Spinner
        ColorDropDownLabel          matlab.ui.control.Label
        ColorDropDown               matlab.ui.control.DropDown
        ColorDropDown_2Label        matlab.ui.control.Label
        ColorDropDown_2             matlab.ui.control.DropDown
        AxesTab                     matlab.ui.container.Tab
        EndtimeEditField            matlab.ui.control.NumericEditField
        EndtimeEditField_2          matlab.ui.control.NumericEditField
        XLIMEditFieldLabel          matlab.ui.control.Label
        XLIMEditField               matlab.ui.control.NumericEditField
        YLIMEditFieldLabel          matlab.ui.control.Label
        YLIMEditField               matlab.ui.control.NumericEditField
        XLABELEditFieldLabel        matlab.ui.control.Label
        XLABELEditField             matlab.ui.control.EditField
        YLABELEditFieldLabel        matlab.ui.control.Label
        YLABELEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        cfg
        graph
    end
    
    methods (Access = private)
        
        %% HELPER - UpdateGUI
        function updateGUI(app)
        end
        
        %% HELPER - Get focus
        function getFocus(app)
            app.resultViewFigure.Visible = 'off';
            app.resultViewFigure.Visible = 'on';
        end
        
        %% HELPER - Load results
        function loadResults(app,path,file)
            % Clear the current workspace:
            evalin("base",'clearvars -except graph');
            
            % Load study and result file:
            temp = load([path file]);
            app.cfg = temp.cfg;
            assignin('base','cfg',temp.cfg);
            assignin('base','result',temp.result);
            
            % Init graph structure:
            app.graph = mvpalab_plotinit(app.cfg);
            assignin('base','graph',app.graph);
            
            
            % Try to import stats data to base workspace:
            if exist([path filesep 'stats.mat'],'file')
                temp = load([path filesep 'stats.mat']);
                assignin('base','stats',temp.stats);
                app.enableStats('on');
            else
                app.enableStats('off');
            end
            
            % Try to import permuted maps to base workspace:
            if exist([path filesep 'permaps.mat'],'file')
                temp = load([path filesep 'permaps.mat']);
                assignin('base','permaps',temp.permaps);
            end
        end
        
        %% HELPER - Update graph structure
        function updateGraph (app)
            assignin('base','graph',app.graph);
        end
        
        %% HELPER - Enable stats panel
        function enableStats (app,value)
            app.plotAboveCluster.Enable = value;
            app.plotBelowCluster.Enable = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init graph structure and assign to base workspace:
            app.graph = mvpalab_plotinit();
            assignin('base','graph',app.graph);
        end

        % Button pushed function: plotResutsButton
        function plotResutsButtonPushed(app, event)
            evalin("base", 'plotController');
        end

        % Value changed function: colorSchemeSelector
        function colorSchemeSelectorValueChanged(app, event)
            app.graph.colorMap = getfield(app.graph.grads,...
                app.colorSchemeSelector.Value);
            colormap(app.graph.colorMap);
        end

        % Button pushed function: loadResultButton
        function loadResultButtonButtonPushed(app, event)
            % Disable load and plot button:
            app.loadResultButton.Text = 'Loading data...';
            app.loadResultButton.Enable = 'off';
            app.plotResutsButton.Enable = 'off';
            
            % File selector:
            openDir = fullfile(getenv('HOME'),'Desktop');
            [file,path] = uigetfile([openDir '/result.mat'], ...
                'Select a result.mat file');
            app.getFocus();
            
            if file
                if strcmp(file,'result.mat')
                    % Update text field
                    app.loadResultField.Value = [path file];
                    
                    % Load data in the base workspace:
                    app.loadResults(path,file);
                    app.updateGUI();
                    app.getFocus();
                else
                    uialert(app.resultViewFigure,...
                        'Please, select a correct result.mat file',...
                        'Warning');
                end
            else
                % Clear dir:
                app.loadResultField.Value = '';
            end
            
            % Enable load button
            app.loadResultButton.Text = 'Select file';
            app.loadResultButton.Enable = 'on';
            app.plotResutsButton.Enable = 'on';
        end

        % Value changed function: plotAboveCluster
        function plotAboveClusterValueChanged(app, event)
            app.graph.stats.above = app.plotAboveCluster.Value;
            app.updateGraph();
        end

        % Value changed function: plotBelowCluster
        function plotBelowClusterValueChanged(app, event)
            app.graph.stats.below = app.plotBelowCluster.Value;
            app.updateGraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.resultViewFigure = uifigure('Visible', 'off');
            app.resultViewFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.resultViewFigure);
            app.MVPALogo = uiimage(app.Panel);
            app.MVPALogo.ImageSource = 'mvpalab.png';
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.resultFilePanel = uipanel(app.resultViewFigure);
            app.resultFilePanel.Title = 'Result file location:';
            app.loadResultButton = uibutton(app.resultFilePanel, 'push');
            app.loadResultButton.ButtonPushedFcn = createCallbackFcn(app, @loadResultButtonButtonPushed, true);
            app.loadResultButton.Text = 'Select file';
            app.ResultfileLabel = uilabel(app.resultFilePanel);
            app.ResultfileLabel.Text = 'Result file:';
            app.loadResultField = uieditfield(app.resultFilePanel, 'text');
            app.loadResultField.Editable = 'off';
            app.plotResutsButton = uibutton(app.resultViewFigure, 'push');
            app.plotResutsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResutsButtonPushed, true);
            app.plotResutsButton.Enable = 'off';
            app.plotResutsButton.Text = 'Plot results';
            app.TabGroup = uitabgroup(app.resultViewFigure);
            app.BasicconfigurationTab = uitab(app.TabGroup);
            app.BasicconfigurationTab.Title = 'Basic configuration';
            app.ErrormetricLabel = uilabel(app.BasicconfigurationTab);
            app.ErrormetricLabel.Text = 'Error metric:';
            app.ErrormetricDropDown = uidropdown(app.BasicconfigurationTab);
            app.ErrormetricDropDown.Items = {'Standard deviation (STD)', 'Standard error mean (SEM)'};
            app.ErrormetricDropDown.Value = 'Standard deviation (STD)';
            app.SmoothdataSpinnerLabel = uilabel(app.BasicconfigurationTab);
            app.SmoothdataSpinnerLabel.Text = 'Smooth data:';
            app.SmoothdataSpinner = uispinner(app.BasicconfigurationTab);
            app.SmoothdataSpinner.Limits = [1 Inf];
            app.SmoothdataSpinner.Value = 1;
            app.ShadealphaLabel = uilabel(app.BasicconfigurationTab);
            app.ShadealphaLabel.Text = 'Shade alpha:';
            app.ShadealphaSpinner = uispinner(app.BasicconfigurationTab);
            app.ShadealphaSpinner.Step = 0.1;
            app.ShadealphaSpinner.Limits = [0 1];
            app.LinestyleDropDownLabel = uilabel(app.BasicconfigurationTab);
            app.LinestyleDropDownLabel.Text = 'Line style:';
            app.LinestyleDropDown = uidropdown(app.BasicconfigurationTab);
            app.LinestyleDropDown.Items = {'none', 'line', 'dot', 'dot line'};
            app.LinestyleDropDown.ItemsData = {'none', '-', '.', '-.'};
            app.LinestyleDropDown.Value = 'none';
            app.ShadecolorDropDownLabel = uilabel(app.BasicconfigurationTab);
            app.ShadecolorDropDownLabel.Text = 'Shade color:';
            app.ShadecolorDropDown = uidropdown(app.BasicconfigurationTab);
            app.ShadecolorDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ShadecolorDropDown.Value = '1';
            app.inverColorScheme = uicheckbox(app.BasicconfigurationTab);
            app.inverColorScheme.Text = 'Invert color scheme';
            app.ColorschemeLabel = uilabel(app.BasicconfigurationTab);
            app.ColorschemeLabel.Text = 'Color scheme:';
            app.colorSchemeSelector = uidropdown(app.BasicconfigurationTab);
            app.colorSchemeSelector.Items = {'MVPAlab', 'Sun', 'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.colorSchemeSelector.ItemsData = {'mvpalab', 'sun', 'mercury', 'venus', 'earth', 'mars', 'jupiter', 'saturn', 'uranus', 'neptune', ''};
            app.colorSchemeSelector.ValueChangedFcn = createCallbackFcn(app, @colorSchemeSelectorValueChanged, true);
            app.colorSchemeSelector.Value = 'mvpalab';
            app.TemporalgeneralizationTab = uitab(app.TabGroup);
            app.TemporalgeneralizationTab.Title = 'Temporal generalization';
            app.EndtimeEditField_3 = uieditfield(app.TemporalgeneralizationTab, 'numeric');
            app.CAXISEditFieldLabel = uilabel(app.TemporalgeneralizationTab);
            app.CAXISEditFieldLabel.Text = 'CAXIS:';
            app.CAXISEditField = uieditfield(app.TemporalgeneralizationTab, 'numeric');
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.plotAboveCluster = uicheckbox(app.StatisticsTab);
            app.plotAboveCluster.ValueChangedFcn = createCallbackFcn(app, @plotAboveClusterValueChanged, true);
            app.plotAboveCluster.Text = 'Highlight above chance significant clusters';
            app.plotBelowCluster = uicheckbox(app.StatisticsTab);
            app.plotBelowCluster.ValueChangedFcn = createCallbackFcn(app, @plotBelowClusterValueChanged, true);
            app.plotBelowCluster.Text = 'Highlight below chance significant clusters';
            app.HighlightmodeDropDownLabel = uilabel(app.StatisticsTab);
            app.HighlightmodeDropDownLabel.Text = 'Highlight mode:';
            app.HighlightmodeDropDown = uidropdown(app.StatisticsTab);
            app.HighlightmodeDropDown.Items = {'Shade', 'Points'};
            app.HighlightmodeDropDown.Value = 'Points';
            app.PointspositionLabel = uilabel(app.StatisticsTab);
            app.PointspositionLabel.Text = 'Points position:';
            app.PointspositionSpinner = uispinner(app.StatisticsTab);
            app.PointspositionSpinner.Step = 0.1;
            app.PointspositionSpinner.Limits = [-2 1];
            app.ColorDropDownLabel = uilabel(app.StatisticsTab);
            app.ColorDropDownLabel.Text = 'Color:';
            app.ColorDropDown = uidropdown(app.StatisticsTab);
            app.ColorDropDown_2Label = uilabel(app.StatisticsTab);
            app.ColorDropDown_2Label.Text = 'Color:';
            app.ColorDropDown_2 = uidropdown(app.StatisticsTab);
            app.AxesTab = uitab(app.TabGroup);
            app.AxesTab.Title = 'Axes';
            app.EndtimeEditField = uieditfield(app.AxesTab, 'numeric');
            app.EndtimeEditField_2 = uieditfield(app.AxesTab, 'numeric');
            app.XLIMEditFieldLabel = uilabel(app.AxesTab);
            app.XLIMEditFieldLabel.Text = 'XLIM:';
            app.XLIMEditField = uieditfield(app.AxesTab, 'numeric');
            app.YLIMEditFieldLabel = uilabel(app.AxesTab);
            app.YLIMEditFieldLabel.Text = 'YLIM:';
            app.YLIMEditField = uieditfield(app.AxesTab, 'numeric');
            app.XLABELEditFieldLabel = uilabel(app.AxesTab);
            app.XLABELEditFieldLabel.Text = 'XLABEL:';
            app.XLABELEditField = uieditfield(app.AxesTab, 'text');
            app.YLABELEditFieldLabel = uilabel(app.AxesTab);
            app.YLABELEditFieldLabel.Text = 'YLABEL:';
            app.YLABELEditField = uieditfield(app.AxesTab, 'text');
            % Show the figure after all components are created
            app.resultViewFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.resultViewFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.resultViewFigure)
        end
    end
end