classdef studyView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StudyconfigurationUIFigure      matlab.ui.Figure
        Button                          matlab.ui.control.Button
        ActionPanel                     matlab.ui.container.Panel
        ActionsPanel                    matlab.ui.container.Panel
        SaveasButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OpenstudyButton                 matlab.ui.control.Button
        CreatenewstudyButton            matlab.ui.control.Button
        MVPAlabToolboxLabel             matlab.ui.control.Label
        versionLabel                    matlab.ui.control.Label
        AnalysisfolderPanel             matlab.ui.container.Panel
        plotResultsButton               matlab.ui.control.Button
        startAnalysisButton             matlab.ui.control.Button
        analysisFolder                  matlab.ui.control.EditField
        ConfigureanalysisLabel          matlab.ui.control.Label
        ConfigureAnalysisTabGroup       matlab.ui.container.TabGroup
        GeneralconfigurationTab         matlab.ui.container.Tab
        ElectrodeselectPanel            matlab.ui.container.Panel
        Typetheindexesoftheselectedelectrodeseg135791113Label  matlab.ui.control.Label
        selectedIndexes                 matlab.ui.control.EditField
        IndexesLabel                    matlab.ui.control.Label
        DatanormalizationPanel          matlab.ui.container.Panel
        normalizationMethod             matlab.ui.control.DropDown
        NormalizationmethodDropDownLabel  matlab.ui.control.Label
        DimensionalityreductionPanel    matlab.ui.container.Panel
        numberOfComponents              matlab.ui.control.Spinner
        NoofcomponentsLabel             matlab.ui.control.Label
        dimentionReductionMethod        matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        DatasmoothingPanel              matlab.ui.container.Panel
        timepointsLabel_2               matlab.ui.control.Label
        SmoothingWindow                 matlab.ui.control.NumericEditField
        LengthLabel                     matlab.ui.control.Label
        SmoothingMethod                 matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        AnalysistimingPanel             matlab.ui.container.Panel
        timepointsLabel                 matlab.ui.control.Label
        StepSizeField                   matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        FinalTimeField                  matlab.ui.control.NumericEditField
        FinaltimeEditFieldLabel         matlab.ui.control.Label
        StartTimeField                  matlab.ui.control.NumericEditField
        StarttimeLabel                  matlab.ui.control.Label
        FeatureextractionPanel          matlab.ui.container.Panel
        trialAveragePanel               matlab.ui.container.Panel
        randomizeTrials                 matlab.ui.control.CheckBox
        MatchKfold                      matlab.ui.control.CheckBox
        MatchClassSize                  matlab.ui.control.CheckBox
        nTrialsAverage                  matlab.ui.control.Spinner
        NumbertrialstoaverageSpinnerLabel  matlab.ui.control.Label
        featuresPanel                   matlab.ui.container.Panel
        envelopeConfiguration           matlab.ui.container.Panel
        envelopeUpLow                   matlab.ui.control.DropDown
        SelectupperlowerenvLabel        matlab.ui.control.Label
        envelopeMethod                  matlab.ui.control.DropDown
        MethodlengthDropDownLabel       matlab.ui.control.Label
        envelopeLength                  matlab.ui.control.NumericEditField
        featureSelector                 matlab.ui.control.DropDown
        FeaturesDropDownLabel           matlab.ui.control.Label
        TemporalgeneralizationTab       matlab.ui.container.Tab
        ConfigurationPanel              matlab.ui.container.Panel
        stepSizeLabel                   matlab.ui.control.Label
        extractDiagonal                 matlab.ui.control.CheckBox
        timepointsLabel_3               matlab.ui.control.Label
        trainTestStepSizeField          matlab.ui.control.NumericEditField
        TrainTestwindowsPanel           matlab.ui.container.Panel
        trainFinalTimeField             matlab.ui.control.NumericEditField
        TrainfinaltimeLabel             matlab.ui.control.Label
        testFinalTimeField              matlab.ui.control.NumericEditField
        TestfinaltimeLabel              matlab.ui.control.Label
        msLabel_6                       matlab.ui.control.Label
        testStartTimeField              matlab.ui.control.NumericEditField
        TeststarttimeLabel              matlab.ui.control.Label
        msLabel_5                       matlab.ui.control.Label
        trainStartTimeField             matlab.ui.control.NumericEditField
        TrainstarttimeLabel             matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        TemporalgeneralizationanalysisPanel  matlab.ui.container.Panel
        TemporalGeneralization          matlab.ui.control.CheckBox
        ClassificationmodelTab          matlab.ui.container.Tab
        permuteLabels                   matlab.ui.control.CheckBox
        ModeloptimizationPanel          matlab.ui.container.Panel
        nfolds5Label                    matlab.ui.control.Label
        ParamsBoxConstraintLabel        matlab.ui.control.Label
        OptimizergridsearchLabel        matlab.ui.control.Label
        DefaultconfigurationLabel       matlab.ui.control.Label
        optimizeHyperparameters         matlab.ui.control.CheckBox
        ModelconfigurationPanel         matlab.ui.container.Panel
        classificationKernel            matlab.ui.control.DropDown
        ClassificationkernelDropDownLabel  matlab.ui.control.Label
        classificationMethod            matlab.ui.control.DropDown
        ClassificationmethodDropDownLabel  matlab.ui.control.Label
        parallelCompuitationLabel       matlab.ui.control.Label
        parallelComputation             matlab.ui.control.Switch
        ParallelcomputationSwitchLabel  matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        roc                             matlab.ui.control.CheckBox
        f1Score                         matlab.ui.control.CheckBox
        recall                          matlab.ui.control.CheckBox
        precision                       matlab.ui.control.CheckBox
        weightsVector                   matlab.ui.control.CheckBox
        confusionMatrix                 matlab.ui.control.CheckBox
        areaUnderCurve                  matlab.ui.control.CheckBox
        meanAccuracy                    matlab.ui.control.CheckBox
        CrossvalidationPanel            matlab.ui.container.Panel
        nFoldsLabel                     matlab.ui.control.Label
        nFolds                          matlab.ui.control.Spinner
        CVProcedure                     matlab.ui.control.DropDown
        ProcedureDropDownLabel          matlab.ui.control.Label
        StatisticsTab                   matlab.ui.container.Tab
        PercentilethresholdsPanel       matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ClusterSignificanceLevel        matlab.ui.control.NumericEditField
        ClustersignificancelevelEditFieldLabel  matlab.ui.control.Label
        GroupSignificanceLevel          matlab.ui.control.NumericEditField
        GroupsignificancelevelEditFieldLabel  matlab.ui.control.Label
        ClusterbasedpermutationanalysisPanel  matlab.ui.container.Panel
        SelectanalysisDropDown          matlab.ui.control.DropDown
        SelectanalysisDropDownLabel     matlab.ui.control.Label
        permutationAnalysisFlag         matlab.ui.control.CheckBox
        PermutedmapsPanel               matlab.ui.container.Panel
        plotNullDistribution            matlab.ui.control.CheckBox
        PermutedMapsGroup               matlab.ui.control.Spinner
        NumberofpermutedmapsgrouplevelSpinnerLabel  matlab.ui.control.Label
        PermutedMapsSubject             matlab.ui.control.Spinner
        NumberofpermutedmapspersubjectLabel  matlab.ui.control.Label
        SlidingfilterTab                matlab.ui.container.Tab
        FilteroptionPanel               matlab.ui.container.Panel
        plotFerqResponse                matlab.ui.control.Button
        filterWindowType                matlab.ui.control.DropDown
        WindowtypeDropDownLabel         matlab.ui.control.Label
        filterType                      matlab.ui.control.DropDown
        FiltertypeDropDownLabel         matlab.ui.control.Label
        filterBW                        matlab.ui.control.Spinner
        FilterbandwidthHzSpinnerLabel   matlab.ui.control.Label
        filterOrder                     matlab.ui.control.Spinner
        FilterorderevenSpinnerLabel     matlab.ui.control.Label
        FrequencyconfigurationPanel     matlab.ui.container.Panel
        spacingScale                    matlab.ui.control.DropDown
        SpacingscaleLabel               matlab.ui.control.Label
        nFrequencySteps                 matlab.ui.control.Spinner
        NoofstepsLabel                  matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        inferiorFrequencyLimit          matlab.ui.control.Spinner
        InferiorlimitSpinnerLabel       matlab.ui.control.Label
        superiorFrequencyLimit          matlab.ui.control.Spinner
        SuperiorlimitSpinnerLabel       matlab.ui.control.Label
        SlidingfilteranalysisPanel      matlab.ui.container.Panel
        sfPerformanceMetric             matlab.ui.control.DropDown
        PerformancemetricDropDownLabel  matlab.ui.control.Label
        sfAnalysisFlag                  matlab.ui.control.CheckBox
        sfFilesLocation                 matlab.ui.control.Button
        AlternativeLocationFolder       matlab.ui.control.EditField
        AlternativelocationforfilteredfilesEditFieldLabel  matlab.ui.control.Label
        warning                         matlab.ui.control.Label
        ImportdataLabel                 matlab.ui.control.Label
        ImportDataTabGroup              matlab.ui.container.TabGroup
        MVPATab                         matlab.ui.container.Tab
        conditionBLamp                  matlab.ui.control.Button
        conditionALamp                  matlab.ui.control.Button
        conditionIdentifierB            matlab.ui.control.EditField
        conditionIdentifierA            matlab.ui.control.EditField
        showSelectedFilesB              matlab.ui.control.Button
        showSelectedFilesA              matlab.ui.control.Button
        tabGroupTitle                   matlab.ui.control.Label
        ConditionBButton                matlab.ui.control.Button
        ConditionAButton                matlab.ui.control.Button
        ConditionBField                 matlab.ui.control.EditField
        ConditionAField                 matlab.ui.control.EditField
        MVCCTab                         matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        Context1Tab                     matlab.ui.container.Tab
        condition1BLamp                 matlab.ui.control.Button
        condition1ALamp                 matlab.ui.control.Button
        conditionIdentifier1B           matlab.ui.control.EditField
        conditionIdentifier1A           matlab.ui.control.EditField
        condition1BField                matlab.ui.control.EditField
        condition1AField                matlab.ui.control.EditField
        showSelectedButton1B            matlab.ui.control.Button
        showSelectedButton1A            matlab.ui.control.Button
        condition1BButton               matlab.ui.control.Button
        condition1AButton               matlab.ui.control.Button
        Context2Tab                     matlab.ui.container.Tab
        condition2BLamp                 matlab.ui.control.Button
        condition2ALamp                 matlab.ui.control.Button
        condition2BField                matlab.ui.control.EditField
        condition2AField                matlab.ui.control.EditField
        conditionIdentifier2B           matlab.ui.control.EditField
        conditionIdentifier2A           matlab.ui.control.EditField
        showSelectedButton2B            matlab.ui.control.Button
        condition2BButton               matlab.ui.control.Button
        showSelectedButton2A            matlab.ui.control.Button
        condition2AButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        initView;   % Main app
        cfg struct; % Study configuration
        colors = struct(...
            'green', [0.47,0.67,0.19],...
            'red', [0.64,0.08,0.18],...
            'white', [1,1,1]);
    end
    
    methods (Access = private)
        % HELPER - Folder selector
        function path = selectFolder(app)
            openDir = fullfile(getenv('HOME'),'Desktop');
            path = uigetdir(openDir,'Select folder');
            app.getFocus();
        end
        
        % HELPER - Multifiles selector
        function [files,path] = multiFileSelection(...
                app,field,lamp,dir)
            
            if ~isempty(dir)
                openDir = dir;
            else
                openDir = app.cfg.location;
            end
            
            % Data filter
            formatFilter = '/*.*';
            
            [files,path] = uigetfile(...
                [openDir formatFilter],'Select files','MultiSelect','On');
            app.getFocus();
            
            if ischar(files); files = {files}; end
            try 
                if files == 0; files = {}; end
                if path == 0; path = {}; end
            catch 
            end
            
            if ~isempty(files)
                if app.checkFileFormat(files)
                    lamp.BackgroundColor = app.colors.green;
                    field.Value = [int2str(length(files))...
                        ' subjects` files selected'];
                else
                    field.Value = 'Please, select valid format files';
                    lamp.BackgroundColor = app.colors.red;
                end
            else
                lamp.BackgroundColor = app.colors.white;
                field.Value = 'Please select subjects` files';
            end
        end
        
        % HELPER - Check subject file format:
        function val = checkFileFormat(app,file)
            val = false;
            if iscell(file)
                val = true;
            end
        end
        
        % HELPER - Check if start Analysis:
        function val = checkIfStartAnalysis(app)
            val = 'Off';
            if strcmp(app.cfg.analysis,'MVPA')
                if ~isempty(app.cfg.study.dataFiles{1,1}) && ...
                        ~isempty(app.cfg.study.dataFiles{1,2}) && ...
                        ~isempty(app.cfg.study.conditionIdentifier{1,1}) && ...
                        ~isempty(app.cfg.study.conditionIdentifier{1,2})
                    val = 'On';
                end
            else
                if ~isempty(app.cfg.study.dataFiles{1,1}) && ...
                        ~isempty(app.cfg.study.dataFiles{1,2}) && ...
                        ~isempty(app.cfg.study.dataFiles{2,1}) && ...
                        ~isempty(app.cfg.study.dataFiles{2,2}) && ...
                        ~isempty(app.cfg.study.conditionIdentifier{1,1}) && ...
                        ~isempty(app.cfg.study.conditionIdentifier{1,2}) && ...
                        ~isempty(app.cfg.study.conditionIdentifier{2,1}) && ...
                        ~isempty(app.cfg.study.conditionIdentifier{2,2})
                        
                    val = 'On';
                end
            end
        end
        
        % HELPER - Check results
        function val = checkResults(app)
            val = 'Off';
             if isfile([app.cfg.location filesep ...
                    'results' filesep 'result.mat'])
                 val = 'On';
             end
        end
        
        % HELPER - Gef focus:
        function getFocus(app)
            app.StudyconfigurationUIFigure.Visible = 'off';
            app.StudyconfigurationUIFigure.Visible = 'on';
        end
        
        % HELPER - Check if the train and test windows of the 
        % temporal generalization matrix are identical
        function val = checkDiagonal(app)
            
            trainStart = app.trainStartTimeField.Value;
            trainEnd = app.trainFinalTimeField.Value;
            testStart = app.testStartTimeField.Value;
            testEnd = app.testFinalTimeField.Value;
            
            if trainStart == testStart && trainEnd == testEnd
                val = true; 
            else
                val = false;
            end
        end
        
        % HELPER - When the train/test time windows change and 
        % they are not identical, the extraction of the diagonal
        % should be disabled.
        function disableDiagonalIfNeeded(app)
            if ~app.checkDiagonal()
                app.extractDiagonal.Value = 0;
                app.cfg.classmodel.extdiag = 0;
            end
        end
        
        % HELPER - Update GUI
        function updateGUI(app)
            
            % Update base workspace:
            app.updateWorkspace();
            
            % Analysis folder:
            app.analysisFolder.Value = app.cfg.location;
            
            % Analysis tab:
            if strcmp(app.cfg.analysis,'MVPA')
                app.ImportDataTabGroup.SelectedTab = ... 
                    app.ImportDataTabGroup.Children(1);
                app.ImportDataTabGroup.Children(2).Title = '';
            else
                app.ImportDataTabGroup.SelectedTab = ... 
                    app.ImportDataTabGroup.Children(2);
                app.ImportDataTabGroup.Children(1).Title = '';
            end
            
            % Data files:
            files = app.cfg.study.dataFiles;
            if ~isempty(files{1,1})
                app.conditionALamp.BackgroundColor = app.colors.green;
                app.condition1ALamp.BackgroundColor = app.colors.green;
                
                app.ConditionAField.Value = [int2str(length(files{1,1}))...
                        ' subjects` files selected'];
                app.condition1AField.Value = [int2str(length(files{1,1}))...
                        ' subjects` files selected'];
            end
            
            if ~isempty(files{1,2})
                app.conditionBLamp.BackgroundColor = app.colors.green;
                app.condition1BLamp.BackgroundColor = app.colors.green;
                
                app.ConditionBField.Value = [int2str(length(files{1,2}))...
                        ' subjects` files selected'];
                app.condition1BField.Value = [int2str(length(files{1,2}))...
                        ' subjects` files selected'];
            end
            
            if ~isempty(files{2,1})
                app.condition2ALamp.BackgroundColor = app.colors.green;
                
                app.condition2AField.Value = [int2str(length(files{2,1}))...
                        ' subjects` files selected'];
            end
            
            if ~isempty(files{2,2})
                app.condition2BLamp.BackgroundColor = app.colors.green;
                
                app.condition2BField.Value = [int2str(length(files{2,2}))...
                        ' subjects` files selected'];
            end
            
            % Condition identifiers:
            if strcmp(app.cfg.analysis,'MVPA')
                app.conditionIdentifierA.Value = ...
                    app.cfg.study.conditionIdentifier{1,1};
                app.conditionIdentifierB.Value = ...
                    app.cfg.study.conditionIdentifier{1,2};
            else
                app.conditionIdentifier1A.Value = ...
                    app.cfg.study.conditionIdentifier{1,1};
                app.conditionIdentifier1B.Value = ...
                    app.cfg.study.conditionIdentifier{1,2};
                app.conditionIdentifier2A.Value = ...
                    app.cfg.study.conditionIdentifier{2,1};
                app.conditionIdentifier2B.Value = ...
                    app.cfg.study.conditionIdentifier{2,2};
            end
            
            % Check if start analysis:
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Feature extraction:
            app.featureSelector.Value = app.cfg.feature;
            
            if strcmp(app.cfg.feature,'voltage')
                app.envelopeConfiguration.Visible = 'Off';
            end
            app.envelopeLength.Value = app.cfg.powenv.length;
            app.envelopeMethod.Value = app.cfg.powenv.method;
            app.envelopeUpLow.Value = app.cfg.powenv.uplow;
            
            app.MatchClassSize.Value = app.cfg.classsize.match;
            app.MatchKfold.Value = app.cfg.classsize.matchkfold;
            app.nTrialsAverage.Value = app.cfg.trialaver.ntrials;
            app.randomizeTrials.Value = false;
            if strcmp(app.cfg.trialaver.order,'rand')
                app.randomizeTrials.Value = true;
            end

            % Dimension reduction:
            app.dimentionReductionMethod.Value = app.cfg.dimred.method;
            app.numberOfComponents.Value = app.cfg.dimred.ncomp;
            
            % Data normalization:
            app.normalizationMethod.Value = num2str(app.cfg.normdata);
            
            % Data smooth:
            app.SmoothingMethod.Value = app.cfg.smoothdata.method;
            app.SmoothingWindow.Value = app.cfg.smoothdata.window;
            
            % Time:
            app.StartTimeField.Value = app.cfg.tm.tpstart;
            app.FinalTimeField.Value = app.cfg.tm.tpend;
            
            app.trainStartTimeField.Value = app.cfg.tm.tpstart;
            app.trainFinalTimeField.Value = app.cfg.tm.tpend;
            
            app.testStartTimeField.Value = app.cfg.tm.tpstart_;
            app.testFinalTimeField.Value = app.cfg.tm.tpend_;
            
            app.StepSizeField.Value = app.cfg.tm.tpsteps;
            app.trainTestStepSizeField.Value = app.cfg.tm.tpsteps;
            
            % Electrode selection:
            app.selectedIndexes.Value = num2str(app.cfg.channels.selected);
            
            % Classification model:
            app.TemporalGeneralization.Value = app.cfg.classmodel.tempgen;
            app.extractDiagonal.Value = app.cfg.classmodel.extdiag;
           
            [app.classificationKernel.ItemsData,...
                app.classificationKernel.Items] = getKernelList(...
                app,app.cfg.classmodel.method);
            app.classificationMethod.Value = app.cfg.classmodel.method;
            app.classificationKernel.Value = app.cfg.classmodel.kernel;
            
            % Parallel computation:
            if ~license('test','Distrib_Computing_Toolbox')
                app.parallelComputation.Value = '0';
                app.parallelComputation.Enable = 'Off';
                app.cfg.classmodel.parcomp = 0;
                app.parallelCompuitationLabel.Text = ...
                    'Parallel computing toolbox required';
            else
                app.parallelComputation.Enable = 'On';
                app.parallelComputation.Value = num2str( ...
                    app.cfg.classmodel.parcomp);
                app.parallelCompuitationLabel.Text = ...
                    'Parallel computing toolbox installed';
            end
            
            app.permuteLabels.Value = app.cfg.classmodel.permlab;
            
            app.areaUnderCurve.Value = app.cfg.classmodel.auc;
            app.roc.Value = app.cfg.classmodel.roc;
            app.precision.Value = app.cfg.classmodel.precision;
            app.recall.Value = app.cfg.classmodel.recall;
            app.f1Score.Value = app.cfg.classmodel.f1score;
            app.confusionMatrix.Value = app.cfg.classmodel.confmat;
            app.weightsVector.Value = app.cfg.classmodel.wvector;
            
            app.optimizeHyperparameters.Value = ...
                app.cfg.classmodel.optimize.flag;
            
            % Cross-validation:
            if strcmp(app.cfg.analysis,'MVCC')
                app.CVProcedure.ItemsData = ...
                    {'none';'kfold';'loo'};
                app.CVProcedure.Items = ...
                    {'None';'k-fold';'Leave-one-out'}; 
                app.CVProcedure.Value = app.cfg.cv.method;
            end
            
            app.CVProcedure.Value = app.cfg.cv.method;
            app.nFolds.Value = app.cfg.cv.nfolds;
            
            if strcmp(app.cfg.cv.method,'kfold')
                app.nFolds.Visible = 'On';
                app.nFoldsLabel.Visible = 'On';
            else
                app.nFolds.Visible = 'Off';
                app.nFoldsLabel.Visible = 'Off';
            end
            
            % Stats:
            app.permutationAnalysisFlag.Value = app.cfg.stats.flag;
            app.plotNullDistribution.Value = app.cfg.stats.shownulldis;
            app.PermutedMapsSubject.Value = app.cfg.stats.nper;
            app.PermutedMapsGroup.Value = app.cfg.stats.nperg;
            app.ClusterSignificanceLevel.Value = app.cfg.stats.pclust;
            app.GroupSignificanceLevel.Value = app.cfg.stats.pgroup;
            app.SelectanalysisDropDown.Value = num2str(app.cfg.stats.tails);
            
            % Sliding filter:
            app.sfAnalysisFlag.Value = app.cfg.sf.flag;
            app.cfg.sf.hbw;
            app.AlternativeLocationFolder.Value = app.cfg.sf.filesLocation;
            app.sfPerformanceMetric.Value = app.cfg.sf.metric;
            app.superiorFrequencyLimit.Value = app.cfg.sf.hfreq;
            app.inferiorFrequencyLimit.Value = app.cfg.sf.lfreq;
            app.spacingScale.Value = app.cfg.sf.fspac;
            app.nFrequencySteps.Value = app.cfg.sf.nfreq;
            
            % Filter 
            app.filterType.Value = app.cfg.sf.ftype;
            app.filterBW.Value = app.cfg.sf.bw;
            app.filterOrder.Value = app.cfg.sf.order;
            app.filterWindowType.Value = app.cfg.sf.wtype;
        end
        
        % HELPER - Get kernel list
        function [itemsData, items] = getKernelList (app,method)
            if strcmp(method,'svm')
                items = {'Linear';'Gaussian';'Radial basis function (RBF)';
                    'Polynomial'};
                itemsData = {'linear';'gaussian';'rbf';'polynomial'};
            elseif strcmp(method,'da')
                items = {'Linear';'Quadratic'};
                itemsData = {'linear';'quadratic'};
            end
        end
        
        % HELPER - Check the number of subjects:
        function flag = checkNumberOfSubjects(app)
            msg = 'Please select the same number of subjects for all conditions.';
            title = 'Warning';
            flag = true;
            
            if strcmp(app.cfg.analysis,'MVPA')
                if length(app.cfg.study.dataFiles{1,1})...
                        ~= length(app.cfg.study.dataFiles{1,2})
                    mvpalab_dialog(app.StudyconfigurationUIFigure, ...
                        msg, title, 'warning');
                    flag = false;
                end
            elseif strcmp(app.cfg.analysis,'MVCC')
                if length(app.cfg.study.dataFiles{1,1})...
                        ~= length(app.cfg.study.dataFiles{1,2}) || ...
                        length(app.cfg.study.dataFiles{2,1})...
                        ~= length(app.cfg.study.dataFiles{2,2})
                    mvpalab_dialog(app.StudyconfigurationUIFigure, ...
                        msg, title, 'warning');
                    flag = false;
                end
            end
        end
        
        % HELPER - Update cfg struct in the workspace:
        function updateWorkspace(app)
            assignin('base','cfg',app.cfg);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initView)
            % Store initView app object and update cfg field:
            app.initView = initView;
            app.cfg = initView.cfg;
            app.versionLabel.Text = mvpalab_getversion();
            
            % Check CFG structure integrity:
            app.cfg = mvpalab_checkcfg(app.cfg);
            
            % Update gui parameters:
            app.updateGUI();
        end

        % Close request function: StudyconfigurationUIFigure
        function studyConfigurationUIClose(app, event)
            % Enable button in initView
            try
                if ~app.initView.isNew
                    app.initView.LoadButton.Enable = 'on';
                end
            catch
            end
            delete(app)
        end

        % Button pushed function: CreatenewstudyButton
        function createNewStudy(app, event)
            % File selector:
            openDir = fullfile(getenv('HOME'),'Desktop');
            studyLocation = uigetdir(openDir,'Select folder');
            app.getFocus();

            if studyLocation
                % If a previous study exists, select another location:
                if isfile([studyLocation '/cfg.mat'])
                    % Show warning and disable conf button:
                    warndlg(['This folder already contains a study ' ...
                        'file. Please select an empty folder.'],'Warning');
                    app.NewIcon.Enable = 'on';
                    studyLocation = 0;
                else
                    % Reset cfg struct:
                    type = app.cfg.analysis;
                    app.cfg = mvpalab_init();
                    app.cfg.analysis = type;
                    app.cfg.location = studyLocation;
                    
                    % Save the cfg file:
                    cfg = app.cfg;
                    save([app.cfg.location '/cfg.mat'],'cfg');
                    
                    % Update GUI:
                    app.updateGUI();
                end
            end
        end

        % Button pushed function: OpenstudyButton
        function openPreviousStudy(app, event)
            % File selector:
            openDir = fullfile(getenv('HOME'),'Desktop');
            [file,path] = uigetfile([openDir '/cfg.mat'], ...
                'Select a cfg.mat file');
            app.getFocus();
            
            if file
                if strcmp(file,'cfg.mat')
                    % Load study file:
                    temp = load([path file]);
                    
                    % Update cfg file:
                    app.cfg = temp.cfg;
                    
                    % Update GUI:
                    app.updateGUI();
                    
                else
                    % Show warning and disable load button
                    warndlg(['Please select a valid cfg.mat file.'],'Warning');
                end
            end
        end

        % Button pushed function: SaveButton
        function saveButtonPushed(app, event)
            cfg = app.cfg;
            save([app.cfg.location '/cfg.mat'],'cfg');
        end

        % Button pushed function: SaveasButton
        function saveAsButtonPushed(app, event)
            % Folder selector
            openDir = app.cfg.location;
            newLocation = uigetdir(openDir,'Select folder');
            app.getFocus();
            
            if newLocation
                % If a previous study exists, select another location:
                if isfile([newLocation '/cfg.mat'])
                    % Show warning and disable conf button:
                    warndlg(['This folder already contains a study ' ...
                        'file. Please select an empty folder.'],'Warning');
                    newLocation = 0;
                else
                    % Update text field:
%                     app.StudyLocationField.Value = ...
%                         app.cfg.location;
                    app.analysisFolder.Value = newLocation;
                    app.cfg.location = newLocation;
                    
                    cfg = app.cfg;
                    save([newLocation '/cfg.mat'],'cfg');
                end
            end
        end

        % Selection change function: ImportDataTabGroup
        function analysisTabChanged(app, event)
            if ~strcmp(app.ImportDataTabGroup.SelectedTab.Title,app.cfg.analysis)
                if strcmp(app.cfg.analysis,'MVCC')
                    app.ImportDataTabGroup.SelectedTab = ...
                        app.ImportDataTabGroup.Children(2);
                else
                    app.ImportDataTabGroup.SelectedTab = ...
                        app.ImportDataTabGroup.Children(1);
                end
            end
        end

        % Button pushed function: ConditionAButton
        function conditionAButtonPushed(app, event)
            % Open gui file selector and update gui
            [app.cfg.study.dataFiles{1,1}, app.cfg.study.dataPaths{1,1}] = app.multiFileSelection(...
                app.ConditionAField,...
                app.conditionALamp,...
                app.cfg.study.dataPaths{1,1});
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Update workspace:
            app.updateWorkspace();
        end

        % Button pushed function: ConditionBButton
        function conditionBButtonPushed(app, event)
            % Open gui file selector and update gui
            [app.cfg.study.dataFiles{1,2}, app.cfg.study.dataPaths{1,2}] = app.multiFileSelection(...
                app.ConditionBField,...
                app.conditionBLamp,...
                app.cfg.study.dataPaths{1,2});
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Update workspace:
            app.updateWorkspace();
        end

        % Button pushed function: condition1AButton
        function conditionAAButtonPushed(app, event)
            [app.cfg.study.dataFiles{1,1}, app.cfg.study.dataPaths{1,1}] = app.multiFileSelection(...
                app.condition1AField,...
                app.condition1ALamp,...
                app.cfg.study.dataPaths{1,1});
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Update workspace:
            app.updateWorkspace();
        end

        % Button pushed function: condition1BButton
        function conditionABButtonPushed(app, event)
            [app.cfg.study.dataFiles{1,2}, app.cfg.study.dataPaths{1,2}] = app.multiFileSelection(...
                app.condition1BField,...
                app.condition1BLamp,...
                app.cfg.study.dataPaths{1,2});
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Update workspace:
            app.updateWorkspace();
        end

        % Button pushed function: condition2AButton
        function conditionBAButtonPushed(app, event)
            [app.cfg.study.dataFiles{2,1}, app.cfg.study.dataPaths{2,1}] = app.multiFileSelection(...
                app.condition2AField,...
                app.condition2ALamp,...
                app.cfg.study.dataPaths{2,1});
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Update workspace:
            app.updateWorkspace();
        end

        % Button pushed function: condition2BButton
        function conditionBBButtonPushed(app, event)
            [app.cfg.study.dataFiles{2,2}, app.cfg.study.dataPaths{2,2}] = app.multiFileSelection(...
                app.condition2BField,...
                app.condition2BLamp,...
                app.cfg.study.dataPaths{2,2});
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            
            % Update workspace:
            app.updateWorkspace();
        end

        % Button pushed function: showSelectedButton1A, 
        % showSelectedButton1B, showSelectedButton2A, 
        % showSelectedButton2B, showSelectedFilesA, 
        % showSelectedFilesB
        function showSelectedFilesPushed(app, event)
            msg = 'No files selected.';
            title = 'Selected files:';
            
            % Detect pushed button:
            if strcmp(event.Source.Tag,'A') || strcmp(event.Source.Tag,'AA')
                files = app.cfg.study.dataFiles{1,1};
                path = app.cfg.study.dataPaths{1,1};
            elseif strcmp(event.Source.Tag,'B') || strcmp(event.Source.Tag,'AB')
                files = app.cfg.study.dataFiles{1,2};
                path = app.cfg.study.dataPaths{1,2};
            elseif strcmp(event.Source.Tag,'BA')
                files = app.cfg.study.dataFiles{2,1};
                path = app.cfg.study.dataPaths{2,1};
            elseif strcmp(event.Source.Tag,'BB')
                files = app.cfg.study.dataFiles{2,2};
                path = app.cfg.study.dataPaths{2,2};
            end
            
            if ~isempty(path)
                title = ['Data folder: ' path];
                msg = files;
            end
            mvpalab_dialog(app.StudyconfigurationUIFigure, ...
                msg, title, 'info');
        end

        % Value changed function: StartTimeField
        function startTimeChanged(app, event)
            app.cfg.tm.tpstart = app.StartTimeField.Value;
            app.trainStartTimeField.Value = app.StartTimeField.Value;
            app.disableDiagonalIfNeeded();
            app.updateWorkspace();
        end

        % Value changed function: FinalTimeField
        function finaTimeChanged(app, event)
            app.cfg.tm.tpend = app.FinalTimeField.Value;
            app.trainFinalTimeField.Value = app.FinalTimeField.Value;
            app.disableDiagonalIfNeeded();
            app.updateWorkspace();
        end

        % Value changed function: trainStartTimeField
        function trainStartTimeFieldChanged(app, event)
            app.cfg.tm.tpstart = app.trainStartTimeField.Value;
            app.StartTimeField.Value = app.trainStartTimeField.Value;
            app.disableDiagonalIfNeeded();
            app.updateWorkspace();
        end

        % Value changed function: trainFinalTimeField
        function trainFinalTimeFieldChanged(app, event)
            app.cfg.tm.tpend = app.trainFinalTimeField.Value;
            app.FinalTimeField.Value = app.trainFinalTimeField.Value;
            app.disableDiagonalIfNeeded();
            app.updateWorkspace();
        end

        % Value changed function: testStartTimeField
        function testStartTimeFieldChanged(app, event)
            app.cfg.tm.tpstart_ = app.testStartTimeField.Value;
            app.disableDiagonalIfNeeded();
            app.updateWorkspace();
        end

        % Value changed function: testFinalTimeField
        function testFinalTimeFieldChanged(app, event)
            app.cfg.tm.tpend_ = app.testFinalTimeField.Value;
            app.disableDiagonalIfNeeded();
            app.updateWorkspace();
        end

        % Value changed function: StepSizeField
        function stepSizeChanged(app, event)
            app.cfg.tm.tpsteps = app.StepSizeField.Value;
            app.trainTestStepSizeField.Value = app.StepSizeField.Value;
            app.updateWorkspace();
        end

        % Value changed function: trainTestStepSizeField
        function trainTestStepSizeFieldChanged(app, event)
            app.cfg.tm.tpsteps = app.trainTestStepSizeField.Value;
            app.StepSizeField.Value = app.trainTestStepSizeField.Value;
            app.updateWorkspace();
        end

        % Value changed function: nTrialsAverage
        function nTrialsAverageChanged(app, event)
            app.cfg.trialaver.ntrials = app.nTrialsAverage.Value;
            app.cfg.trialaver.flag = any(app.nTrialsAverage.Value);
            app.updateWorkspace();
        end

        % Value changed function: randomizeTrials
        function randomizeTrialsChanged(app, event)
            app.cfg.trialaver.order = 'sequential';
            if app.randomizeTrials.Value
                app.cfg.trialaver.order = 'rand';
            end
            app.updateWorkspace();
        end

        % Value changed function: MatchClassSize
        function matchClassSizeChanged(app, event)
            app.cfg.classsize.match = app.MatchClassSize.Value;
            app.updateWorkspace();
        end

        % Value changed function: MatchKfold
        function matchKfoldChanged(app, event)
            app.cfg.classsize.matchkfold = app.MatchKfold.Value;
            app.updateWorkspace();
        end

        % Value changed function: dimentionReductionMethod
        function featureSelectionMethodChanged(app, event)
            app.cfg.dimred.method = app.dimentionReductionMethod.Value;
            
            if strcmp(app.cfg.dimred.method,'none')
                app.cfg.dimred.ncomp = 0;
                app.numberOfComponents.Value = 0;
            else
                app.cfg.dimred.ncomp = 3;
                app.numberOfComponents.Value = 3;
            end
            app.updateWorkspace();
        end

        % Value changed function: numberOfComponents
        function numberOfComponentsChanged(app, event)
            app.cfg.dimred.ncomp = app.numberOfComponents.Value;
            
            if app.cfg.dimred.ncomp == 0
                app.dimentionReductionMethod.Value = 'none';
            else
                app.dimentionReductionMethod.Value = 'pca';
            end
            app.updateWorkspace();
        end

        % Value changed function: SmoothingMethod
        function smoothingMethodChanged(app, event)
            app.cfg.smoothdata.method = app.SmoothingMethod.Value;
            
            if strcmp(app.cfg.smoothdata.method,'none')
                app.cfg.smoothdata.window = 1;
                app.SmoothingWindow.Value = 1;
            else
                app.cfg.smoothdata.window = 5;
                app.SmoothingWindow.Value = 5;
            end 
            app.updateWorkspace();
        end

        % Value changed function: SmoothingWindow
        function smoothingWindowChanged(app, event)
            app.cfg.smoothdata.window = app.SmoothingWindow.Value;
            
            if app.SmoothingWindow.Value < 2
                app.cfg.smoothdata.method = 'none';
                app.SmoothingMethod.Value = 'none';
            end
            app.updateWorkspace();
        end

        % Value changed function: TemporalGeneralization
        function temporalGeneralizationChanged(app, event)
            app.cfg.classmodel.tempgen = app.TemporalGeneralization.Value;
            app.updateWorkspace();
        end

        % Value changed function: normalizationMethod
        function normalizationMethodChanged(app, event)
            app.cfg.normdata = str2double(...
                app.normalizationMethod.Value);
            app.updateWorkspace();
        end

        % Value changed function: permutationAnalysisFlag
        function permutationAnalysisFlagChanged(app, event)
            app.cfg.stats.flag = app.permutationAnalysisFlag.Value;
            app.updateWorkspace();
        end

        % Value changed function: PermutedMapsSubject
        function permutedMapsSubjectChanged(app, event)
            app.cfg.stats.nper = app.PermutedMapsSubject.Value;
            app.updateWorkspace();
        end

        % Value changed function: PermutedMapsGroup
        function permutedMapsGroupChanged(app, event)
            app.cfg.stats.nperg = app.PermutedMapsGroup.Value;
            app.updateWorkspace();
        end

        % Value changed function: GroupSignificanceLevel
        function groupSignificanceLevelChanged(app, event)
            app.cfg.stats.pgroup = app.GroupSignificanceLevel.Value;
            app.updateWorkspace();
        end

        % Value changed function: ClusterSignificanceLevel
        function clusterSignificanceLevelChanged(app, event)
            app.cfg.stats.pclust = app.ClusterSignificanceLevel.Value;
            app.updateWorkspace();
        end

        % Value changed function: classificationMethod
        function classificationMethodChanged(app, event)
            % GUI functionality:
            value = app.classificationMethod.Value;
            app.cfg.classmodel.method = value;
            
            % Update kernel list:
            [app.classificationKernel.ItemsData,...
                app.classificationKernel.Items] = getKernelList(app,value);
            app.cfg.classmodel.kernel = 'linear';
            app.classificationKernel.Value = 'linear';
            app.updateWorkspace();
        end

        % Value changed function: classificationKernel
        function classificationKernelChanged(app, event)
            app.cfg.classmodel.kernel = app.classificationKernel.Value;
            app.updateWorkspace();
        end

        % Value changed function: CVProcedure
        function cvMethodChanged(app, event)
            value = app.CVProcedure.Value;
            app.cfg.cv.method = value;
            if strcmp(value,'loo') || strcmp(value,'none')
                app.nFolds.Visible = 'off';
                app.nFoldsLabel.Visible = 'off';
            else
                app.nFolds.Visible = 'on';
                app.nFoldsLabel.Visible = 'on';
                app.cfg.cv.nfolds = 10;
                app.nFolds.Value = 10;
            end
            app.updateWorkspace();
        end

        % Value changed function: nFolds
        function nFoldsChanged(app, event)
            app.cfg.cv.nfolds = app.nFolds.Value;
            app.updateWorkspace();
        end

        % Value changed function: areaUnderCurve
        function areaUnderCurveChanged(app, event)
            app.cfg.classmodel.auc = app.areaUnderCurve.Value;
            app.updateWorkspace();
        end

        % Value changed function: roc
        function rocValueChanged(app, event)
            app.cfg.classmodel.roc = app.roc.Value;
            app.updateWorkspace();
        end

        % Value changed function: confusionMatrix
        function confusionMatrixChanged(app, event)
            app.cfg.classmodel.confmat = app.confusionMatrix.Value;
            app.updateWorkspace();
        end

        % Value changed function: weightsVector
        function weightsVectorChanged(app, event)
            app.cfg.classmodel.wvector = app.weightsVector.Value;
            app.updateWorkspace();
        end

        % Value changed function: precision
        function precisionChanged(app, event)
            app.cfg.classmodel.precision = app.precision.Value;
            app.updateWorkspace();
        end

        % Value changed function: recall
        function recallChanged(app, event)
            app.cfg.classmodel.recall = app.recall.Value;
            app.updateWorkspace();
        end

        % Value changed function: f1Score
        function f1ScoreChanged(app, event)
            app.cfg.classmodel.f1score = app.f1Score.Value;
            app.updateWorkspace();
        end

        % Value changed function: permuteLabels
        function permuteLabelsChanged(app, event)
            app.cfg.classmodel.permlab = app.permuteLabels.Value;
            app.updateWorkspace();
        end

        % Value changed function: optimizeHyperparameters
        function optimizeHyperparametersChanged(app, event)
            app.cfg.classmodel.optimize.flag = ...
                app.optimizeHyperparameters.Value;
            
            % Default configuration:
            app.cfg.classmodel.optimize.params = {'BoxConstraint'};
            app.cfg.classmodel.optimize.opt = struct(...
                'Optimizer','gridsearch',...
                'ShowPlots',false,...
                'Verbose',0,...
                'Kfold', 5);
            app.updateWorkspace();
        end

        % Value changed function: parallelComputation
        function parallelComputationValueChanged(app, event)
            app.cfg.classmodel.parcomp = str2double(...
                app.parallelComputation.Value);
            app.updateWorkspace();
        end

        % Value changed function: inferiorFrequencyLimit
        function inferiorFrequencyLimitChanged(app, event)
            app.cfg.sf.lfreq = app.inferiorFrequencyLimit.Value;
            app.updateWorkspace();
        end

        % Value changed function: superiorFrequencyLimit
        function superiorFrequencyLimitChanged(app, event)
            app.cfg.sf.hfreq = app.superiorFrequencyLimit.Value;
            app.updateWorkspace();
        end

        % Value changed function: nFrequencySteps
        function nFreqStepsChanged(app, event)
            app.cfg.sf.nfreq = app.nFrequencySteps.Value;
            app.updateWorkspace();
        end

        % Value changed function: spacingScale
        function spacingScaleChanged(app, event)
            app.cfg.sf.fspac = app.spacingScale.Value;
            app.updateWorkspace();
        end

        % Value changed function: filterOrder
        function filterOrderChanged(app, event)
            app.cfg.sf.order = app.filterOrder.Value;
            app.updateWorkspace();
        end

        % Value changed function: filterBW
        function filterBWChanged(app, event)
            app.cfg.sf.bw = app.filterBW.Value;
            app.cfg.sf.hbw = app.filterBW.Value/2;
            app.updateWorkspace();
        end

        % Value changed function: filterType
        function filterTypeChanged(app, event)
            app.cfg.sf.ftype = app.filterType.Value;
            app.updateWorkspace();
        end

        % Value changed function: filterWindowType
        function filterWindowTypeChanged(app, event)
            app.cfg.sf.wtype = app.filterWindowType.Value;
            app.updateWorkspace();
        end

        % Button pushed function: plotFerqResponse
        function plotFerquencyResponse(app, event)
            app.cfg = mvpalab_genfreqvec(app.cfg);
            mvpalab_plotfreqresp(app.cfg);
            app.updateWorkspace();
        end

        % Button pushed function: sfFilesLocation
        function sfFilesLocationPushed(app, event)
            path = selectFolder(app);
            app.AlternativeLocationFolder.Value = path;
            app.cfg.sf.filesLocation = path;
            app.updateWorkspace();
        end

        % Value changed function: conditionIdentifier1A, 
        % conditionIdentifier1B, conditionIdentifier2A, 
        % conditionIdentifier2B, conditionIdentifierA, 
        % conditionIdentifierB
        function conditionIdentifierChanged(app, event)
            % Update cfg  structure:
            if strcmp(event.Source.Tag,'A')
                app.cfg.study.conditionIdentifier{1,1} = ...
                    app.conditionIdentifierA.Value;
            elseif strcmp(event.Source.Tag,'B')
                app.cfg.study.conditionIdentifier{1,2} = ...
                    app.conditionIdentifierB.Value;
            elseif strcmp(event.Source.Tag,'1A')
                app.cfg.study.conditionIdentifier{1,1} = ...
                    app.conditionIdentifier1A.Value;
            elseif strcmp(event.Source.Tag,'1B')
                app.cfg.study.conditionIdentifier{1,2} = ...
                    app.conditionIdentifier1B.Value;
            elseif strcmp(event.Source.Tag,'2A')
                app.cfg.study.conditionIdentifier{2,1} = ...
                    app.conditionIdentifier2A.Value;
            elseif strcmp(event.Source.Tag,'2B')
                app.cfg.study.conditionIdentifier{2,2} = ...
                    app.conditionIdentifier2B.Value;
            end
            
            % Check if start analysis:
            app.startAnalysisButton.Enable = app.checkIfStartAnalysis();
            app.updateWorkspace();
        end

        % Value changed function: sfAnalysisFlag
        function sfAnalysisFlagChanged(app, event)
            app.cfg.sf.flag = app.sfAnalysisFlag.Value;
            app.updateWorkspace();
        end

        % Button pushed function: startAnalysisButton
        function startAnalysisPushed(app, event)
            % Check the number of subjects:
            if app.checkNumberOfSubjects()
                app.updateWorkspace();
                if strcmp(app.cfg.analysis,'MVPA') ...
                        && ~app.cfg.sf.flag
                    evalin('base', 'run analysisController');
                elseif strcmp(app.cfg.analysis,'MVCC') ...
                        && ~app.cfg.sf.flag
                    evalin('base', 'run analysisController');
                elseif strcmp(app.cfg.analysis,'MVPA') ...
                        && app.cfg.sf.flag
                    evalin('base', 'run sfilterController');
                elseif strcmp(app.cfg.analysis,'MVCC') ...
                        && app.cfg.sf.flag
                    evalin('base', 'run sfilterController');
                end
                app.cfg = evalin('base','cfg');
            end
        end

        % Button pushed function: plotResultsButton
        function plotResultsButtonPushed(app, event)
            % Load result files:
            resultView;
        end

        % Value changed function: featureSelector
        function featureSelectorChanged(app, event)
            if strcmp(app.featureSelector.Value,'voltage')
                app.envelopeConfiguration.Visible = 'Off';
                app.cfg.feature = 'voltage';
            else
                app.envelopeConfiguration.Visible = 'On';
                app.cfg.feature = 'envelope';
            end
            app.updateWorkspace();
        end

        % Value changed function: envelopeMethod
        function envelopeMethodChanged(app, event)
            app.cfg.powenv.method = app.envelopeMethod.Value;
            app.updateWorkspace();
        end

        % Value changed function: envelopeLength
        function envelopeLengthChanged(app, event)
            app.cfg.powenv.length = app.envelopeLength.Value;
            app.updateWorkspace();
        end

        % Value changed function: envelopeUpLow
        function envelopeUpLowChanged(app, event)
            app.cfg.powenv.uplow = app.envelopeUpLow.Value;
            app.updateWorkspace();
        end

        % Value changed function: extractDiagonal
        function extractDiagonalChanged(app, event)
            % If the train and test windows are the same, the 
            % diagonal of the temporal generalization matrix 
            % can be extracted for time-resolved analysis.
            if app.extractDiagonal.Value
                if app.checkDiagonal()
                    app.cfg.classmodel.extdiag = ...
                        app.extractDiagonal.Value;
                else
                    msg = 'To extract the diagonal for time-resolved analyses, the train and test windows of the Temporal Generalization Matrix should be identical.';
                    title = 'Warning';
                    
                    mvpalab_dialog(...
                        app.StudyconfigurationUIFigure, ...
                        msg, title, 'warning');
                    app.extractDiagonal.Value = 0;
                end
            else
                app.cfg.classmodel.extdiag = ...
                    app.extractDiagonal.Value;
            end
            app.updateWorkspace();
        end

        % Value changed function: plotNullDistribution
        function plotNullDistributionChanged(app, event)
            app.cfg.stats.shownulldis = app.plotNullDistribution.Value;
            app.updateWorkspace();
        end

        % Value changed function: sfPerformanceMetric
        function sfPerformanceMetricChanged(app, event)
            app.cfg.sf.metric = app.sfPerformanceMetric.Value;
            app.updateWorkspace();
        end

        % Callback function
        function differentTimeWindowsValueChanged(app, event)
            if app.differentTimeWindows.Value
                app.testStartTimeField.Editable = 'On';
                app.testFinalTimeField.Editable = 'On';
                app.testStartTimeField.Enable = 'On';
                app.testFinalTimeField.Enable = 'On';
            else
                app.testStartTimeField.Editable = 'Off';
                app.testFinalTimeField.Editable = 'Off';
                app.testStartTimeField.Enable = 'Off';
                app.testFinalTimeField.Enable = 'Off';
            end
            app.updateWorkspace();
        end

        % Value changed function: SelectanalysisDropDown
        function numberOfTailsChanged(app, event)
            app.cfg.stats.tails = str2double(app.SelectanalysisDropDown.Value);
            app.updateWorkspace();
        end

        % Value changed function: selectedIndexes
        function selectedIndexesValueChanged(app, event)
            idxs = app.selectedIndexes.Value;
            converted = str2double(strsplit(idxs,' '));
            if isnumeric(converted)
                % Remove NaN:
                converted(isnan(converted))=[];
                app.cfg.channels.selected = converted;
                app.cfg = mvpalab_updatechanloc(converted,app.cfg);
            else
                app.cfg.channels.selected = [];
            end
            app.updateWorkspace();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StudyconfigurationUIFigure = uifigure('Visible', 'off');
            app.StudyconfigurationUIFigure.Name = 'Study configuration';
            app.StudyconfigurationUIFigure.CloseRequestFcn = createCallbackFcn(app, @studyConfigurationUIClose, true);
            app.ImportDataTabGroup = uitabgroup(app.StudyconfigurationUIFigure);
            app.ImportDataTabGroup.SelectionChangedFcn = createCallbackFcn(app, @analysisTabChanged, true);
            app.MVPATab = uitab(app.ImportDataTabGroup);
            app.MVPATab.Title = 'MVPA';
            app.ConditionAField = uieditfield(app.MVPATab, 'text');
            app.ConditionAField.Editable = 'off';
            app.ConditionAField.Value = 'Please, select subjects'' files';
            app.ConditionBField = uieditfield(app.MVPATab, 'text');
            app.ConditionBField.Editable = 'off';
            app.ConditionBField.Value = 'Please, select subjects'' files';
            app.ConditionAButton = uibutton(app.MVPATab, 'push');
            app.ConditionAButton.ButtonPushedFcn = createCallbackFcn(app, @conditionAButtonPushed, true);
            app.ConditionAButton.Tag = 'A';
            app.ConditionAButton.Text = '...';
            app.ConditionBButton = uibutton(app.MVPATab, 'push');
            app.ConditionBButton.ButtonPushedFcn = createCallbackFcn(app, @conditionBButtonPushed, true);
            app.ConditionBButton.Tag = 'B';
            app.ConditionBButton.Text = '...';
            app.tabGroupTitle = uilabel(app.MVPATab);
            app.tabGroupTitle.Text = 'Condition identifier:';
            app.showSelectedFilesA = uibutton(app.MVPATab, 'push');
            app.showSelectedFilesA.ButtonPushedFcn = createCallbackFcn(app, @showSelectedFilesPushed, true);
            app.showSelectedFilesA.Tag = 'A';
            app.showSelectedFilesA.Icon = 'tree_.png';
            app.showSelectedFilesA.Text = 'Show selected';
            app.showSelectedFilesB = uibutton(app.MVPATab, 'push');
            app.showSelectedFilesB.ButtonPushedFcn = createCallbackFcn(app, @showSelectedFilesPushed, true);
            app.showSelectedFilesB.Tag = 'B';
            app.showSelectedFilesB.Icon = 'tree_.png';
            app.showSelectedFilesB.Text = 'Show selected';
            app.conditionIdentifierA = uieditfield(app.MVPATab, 'text');
            app.conditionIdentifierA.ValueChangedFcn = createCallbackFcn(app, @conditionIdentifierChanged, true);
            app.conditionIdentifierA.Tag = 'A';
            app.conditionIdentifierB = uieditfield(app.MVPATab, 'text');
            app.conditionIdentifierB.ValueChangedFcn = createCallbackFcn(app, @conditionIdentifierChanged, true);
            app.conditionIdentifierB.Tag = 'B';
            app.conditionALamp = uibutton(app.MVPATab, 'push');
            app.conditionALamp.Enable = 'off';
            app.conditionALamp.Text = '';
            app.conditionBLamp = uibutton(app.MVPATab, 'push');
            app.conditionBLamp.Enable = 'off';
            app.conditionBLamp.Text = '';
            app.MVCCTab = uitab(app.ImportDataTabGroup);
            app.MVCCTab.Title = 'MVCC';
            app.TabGroup = uitabgroup(app.MVCCTab);
            app.Context1Tab = uitab(app.TabGroup);
            app.Context1Tab.Title = 'Context 1';
            app.condition1AButton = uibutton(app.Context1Tab, 'push');
            app.condition1AButton.ButtonPushedFcn = createCallbackFcn(app, @conditionAAButtonPushed, true);
            app.condition1AButton.Tag = 'AA';
            app.condition1AButton.Text = '...';
            app.condition1BButton = uibutton(app.Context1Tab, 'push');
            app.condition1BButton.ButtonPushedFcn = createCallbackFcn(app, @conditionABButtonPushed, true);
            app.condition1BButton.Tag = 'AB';
            app.condition1BButton.Text = '...';
            app.showSelectedButton1A = uibutton(app.Context1Tab, 'push');
            app.showSelectedButton1A.ButtonPushedFcn = createCallbackFcn(app, @showSelectedFilesPushed, true);
            app.showSelectedButton1A.Tag = 'AA';
            app.showSelectedButton1A.Icon = 'tree_.png';
            app.showSelectedButton1A.Text = 'Show selected';
            app.showSelectedButton1B = uibutton(app.Context1Tab, 'push');
            app.showSelectedButton1B.ButtonPushedFcn = createCallbackFcn(app, @showSelectedFilesPushed, true);
            app.showSelectedButton1B.Tag = 'AB';
            app.showSelectedButton1B.Icon = 'tree_.png';
            app.showSelectedButton1B.Text = 'Show selected';
            app.condition1AField = uieditfield(app.Context1Tab, 'text');
            app.condition1AField.Editable = 'off';
            app.condition1AField.Value = 'Please, select subjects'' files';
            app.condition1BField = uieditfield(app.Context1Tab, 'text');
            app.condition1BField.Editable = 'off';
            app.condition1BField.Value = 'Please, select subjects'' files';
            app.conditionIdentifier1A = uieditfield(app.Context1Tab, 'text');
            app.conditionIdentifier1A.ValueChangedFcn = createCallbackFcn(app, @conditionIdentifierChanged, true);
            app.conditionIdentifier1A.Tag = '1A';
            app.conditionIdentifier1B = uieditfield(app.Context1Tab, 'text');
            app.conditionIdentifier1B.ValueChangedFcn = createCallbackFcn(app, @conditionIdentifierChanged, true);
            app.conditionIdentifier1B.Tag = '1B';
            app.condition1ALamp = uibutton(app.Context1Tab, 'push');
            app.condition1ALamp.Enable = 'off';
            app.condition1ALamp.Text = '';
            app.condition1BLamp = uibutton(app.Context1Tab, 'push');
            app.condition1BLamp.Enable = 'off';
            app.condition1BLamp.Text = '';
            app.Context2Tab = uitab(app.TabGroup);
            app.Context2Tab.Title = 'Context 2';
            app.condition2AButton = uibutton(app.Context2Tab, 'push');
            app.condition2AButton.ButtonPushedFcn = createCallbackFcn(app, @conditionBAButtonPushed, true);
            app.condition2AButton.Tag = 'BA';
            app.condition2AButton.Text = '...';
            app.showSelectedButton2A = uibutton(app.Context2Tab, 'push');
            app.showSelectedButton2A.ButtonPushedFcn = createCallbackFcn(app, @showSelectedFilesPushed, true);
            app.showSelectedButton2A.Tag = 'BA';
            app.showSelectedButton2A.Icon = 'tree_.png';
            app.showSelectedButton2A.Text = 'Show selected';
            app.condition2BButton = uibutton(app.Context2Tab, 'push');
            app.condition2BButton.ButtonPushedFcn = createCallbackFcn(app, @conditionBBButtonPushed, true);
            app.condition2BButton.Tag = 'BB';
            app.condition2BButton.Text = '...';
            app.showSelectedButton2B = uibutton(app.Context2Tab, 'push');
            app.showSelectedButton2B.ButtonPushedFcn = createCallbackFcn(app, @showSelectedFilesPushed, true);
            app.showSelectedButton2B.Tag = 'BB';
            app.showSelectedButton2B.Icon = 'tree_.png';
            app.showSelectedButton2B.Text = 'Show selected';
            app.conditionIdentifier2A = uieditfield(app.Context2Tab, 'text');
            app.conditionIdentifier2A.ValueChangedFcn = createCallbackFcn(app, @conditionIdentifierChanged, true);
            app.conditionIdentifier2A.Tag = '2A';
            app.conditionIdentifier2B = uieditfield(app.Context2Tab, 'text');
            app.conditionIdentifier2B.ValueChangedFcn = createCallbackFcn(app, @conditionIdentifierChanged, true);
            app.conditionIdentifier2B.Tag = '2B';
            app.condition2AField = uieditfield(app.Context2Tab, 'text');
            app.condition2AField.Editable = 'off';
            app.condition2AField.Value = 'Please, select subjects'' files';
            app.condition2BField = uieditfield(app.Context2Tab, 'text');
            app.condition2BField.Editable = 'off';
            app.condition2BField.Value = 'Please, select subjects'' files';
            app.condition2ALamp = uibutton(app.Context2Tab, 'push');
            app.condition2ALamp.Enable = 'off';
            app.condition2ALamp.Text = '';
            app.condition2BLamp = uibutton(app.Context2Tab, 'push');
            app.condition2BLamp.Enable = 'off';
            app.condition2BLamp.Text = '';
            app.ImportdataLabel = uilabel(app.StudyconfigurationUIFigure);
            app.ImportdataLabel.Text = 'Import data';
            app.ConfigureAnalysisTabGroup = uitabgroup(app.StudyconfigurationUIFigure);
            app.GeneralconfigurationTab = uitab(app.ConfigureAnalysisTabGroup);
            app.GeneralconfigurationTab.Title = 'General configuration';
            app.FeatureextractionPanel = uipanel(app.GeneralconfigurationTab);
            app.FeatureextractionPanel.Title = '2. Feature extraction:';
            app.featuresPanel = uipanel(app.FeatureextractionPanel);
            app.FeaturesDropDownLabel = uilabel(app.featuresPanel);
            app.FeaturesDropDownLabel.Text = 'Features:';
            app.featureSelector = uidropdown(app.featuresPanel);
            app.featureSelector.Items = {'Raw voltage', 'Power envelope'};
            app.featureSelector.ItemsData = {'voltage', 'envelope'};
            app.featureSelector.ValueChangedFcn = createCallbackFcn(app, @featureSelectorChanged, true);
            app.featureSelector.Value = 'voltage';
            app.envelopeConfiguration = uipanel(app.featuresPanel);
            app.envelopeLength = uieditfield(app.envelopeConfiguration, 'numeric');
            app.envelopeLength.Limits = [1 Inf];
            app.envelopeLength.RoundFractionalValues = 'on';
            app.envelopeLength.ValueChangedFcn = createCallbackFcn(app, @envelopeLengthChanged, true);
            app.envelopeLength.Value = 5;
            app.MethodlengthDropDownLabel = uilabel(app.envelopeConfiguration);
            app.MethodlengthDropDownLabel.Text = 'Method/length:';
            app.envelopeMethod = uidropdown(app.envelopeConfiguration);
            app.envelopeMethod.Items = {'Analytic', 'Peak'};
            app.envelopeMethod.ItemsData = {'analytic', 'peak'};
            app.envelopeMethod.ValueChangedFcn = createCallbackFcn(app, @envelopeMethodChanged, true);
            app.envelopeMethod.Value = 'analytic';
            app.SelectupperlowerenvLabel = uilabel(app.envelopeConfiguration);
            app.SelectupperlowerenvLabel.Text = 'Select upper/lower env:';
            app.envelopeUpLow = uidropdown(app.envelopeConfiguration);
            app.envelopeUpLow.Items = {'Upper', 'Lower'};
            app.envelopeUpLow.ItemsData = {'upper', 'lower'};
            app.envelopeUpLow.ValueChangedFcn = createCallbackFcn(app, @envelopeUpLowChanged, true);
            app.envelopeUpLow.Value = 'upper';
            app.trialAveragePanel = uipanel(app.FeatureextractionPanel);
            app.trialAveragePanel.Title = 'Trial average and balanced datasets:';
            app.NumbertrialstoaverageSpinnerLabel = uilabel(app.trialAveragePanel);
            app.NumbertrialstoaverageSpinnerLabel.Text = 'Number trials to average:';
            app.nTrialsAverage = uispinner(app.trialAveragePanel);
            app.nTrialsAverage.Limits = [0 Inf];
            app.nTrialsAverage.RoundFractionalValues = 'on';
            app.nTrialsAverage.ValueChangedFcn = createCallbackFcn(app, @nTrialsAverageChanged, true);
            app.MatchClassSize = uicheckbox(app.trialAveragePanel);
            app.MatchClassSize.ValueChangedFcn = createCallbackFcn(app, @matchClassSizeChanged, true);
            app.MatchClassSize.Text = 'Match class size';
            app.MatchClassSize.Value = true;
            app.MatchKfold = uicheckbox(app.trialAveragePanel);
            app.MatchKfold.ValueChangedFcn = createCallbackFcn(app, @matchKfoldChanged, true);
            app.MatchKfold.Text = 'Match kfold';
            app.MatchKfold.Value = true;
            app.randomizeTrials = uicheckbox(app.trialAveragePanel);
            app.randomizeTrials.ValueChangedFcn = createCallbackFcn(app, @randomizeTrialsChanged, true);
            app.randomizeTrials.Text = 'Randomize trials';
            app.randomizeTrials.Value = true;
            app.AnalysistimingPanel = uipanel(app.GeneralconfigurationTab);
            app.AnalysistimingPanel.Title = '1. Analysis timing:';
            app.StarttimeLabel = uilabel(app.AnalysistimingPanel);
            app.StarttimeLabel.Text = 'Start time:';
            app.StartTimeField = uieditfield(app.AnalysistimingPanel, 'numeric');
            app.StartTimeField.RoundFractionalValues = 'on';
            app.StartTimeField.ValueChangedFcn = createCallbackFcn(app, @startTimeChanged, true);
            app.FinaltimeEditFieldLabel = uilabel(app.AnalysistimingPanel);
            app.FinaltimeEditFieldLabel.Text = 'Final time:';
            app.FinalTimeField = uieditfield(app.AnalysistimingPanel, 'numeric');
            app.FinalTimeField.RoundFractionalValues = 'on';
            app.FinalTimeField.ValueChangedFcn = createCallbackFcn(app, @finaTimeChanged, true);
            app.msLabel = uilabel(app.AnalysistimingPanel);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.AnalysistimingPanel);
            app.msLabel_2.Text = 'ms';
            app.StepsizeEditFieldLabel = uilabel(app.AnalysistimingPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size:';
            app.StepSizeField = uieditfield(app.AnalysistimingPanel, 'numeric');
            app.StepSizeField.Limits = [1 Inf];
            app.StepSizeField.RoundFractionalValues = 'on';
            app.StepSizeField.ValueChangedFcn = createCallbackFcn(app, @stepSizeChanged, true);
            app.StepSizeField.Value = 1;
            app.timepointsLabel = uilabel(app.AnalysistimingPanel);
            app.timepointsLabel.Text = 'timepoints';
            app.DatasmoothingPanel = uipanel(app.GeneralconfigurationTab);
            app.DatasmoothingPanel.Title = '4. Data smoothing:';
            app.MethodLabel = uilabel(app.DatasmoothingPanel);
            app.MethodLabel.Text = 'Method:';
            app.SmoothingMethod = uidropdown(app.DatasmoothingPanel);
            app.SmoothingMethod.Items = {'No smoothing', 'Moving average', 'Gaussian'};
            app.SmoothingMethod.ItemsData = {'none', 'moving', 'gaussian'};
            app.SmoothingMethod.ValueChangedFcn = createCallbackFcn(app, @smoothingMethodChanged, true);
            app.SmoothingMethod.Value = 'none';
            app.LengthLabel = uilabel(app.DatasmoothingPanel);
            app.LengthLabel.Text = 'Length:';
            app.SmoothingWindow = uieditfield(app.DatasmoothingPanel, 'numeric');
            app.SmoothingWindow.Limits = [1 Inf];
            app.SmoothingWindow.RoundFractionalValues = 'on';
            app.SmoothingWindow.ValueChangedFcn = createCallbackFcn(app, @smoothingWindowChanged, true);
            app.SmoothingWindow.Value = 1;
            app.timepointsLabel_2 = uilabel(app.DatasmoothingPanel);
            app.timepointsLabel_2.Text = 'timepoints';
            app.DimensionalityreductionPanel = uipanel(app.GeneralconfigurationTab);
            app.DimensionalityreductionPanel.Title = '3. Dimensionality reduction:';
            app.MethodDropDownLabel = uilabel(app.DimensionalityreductionPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.dimentionReductionMethod = uidropdown(app.DimensionalityreductionPanel);
            app.dimentionReductionMethod.Items = {'None', 'Principal Component Analysis'};
            app.dimentionReductionMethod.ItemsData = {'none', 'pca'};
            app.dimentionReductionMethod.ValueChangedFcn = createCallbackFcn(app, @featureSelectionMethodChanged, true);
            app.dimentionReductionMethod.Value = 'none';
            app.NoofcomponentsLabel = uilabel(app.DimensionalityreductionPanel);
            app.NoofcomponentsLabel.Text = 'No. of components:';
            app.numberOfComponents = uispinner(app.DimensionalityreductionPanel);
            app.numberOfComponents.Limits = [0 Inf];
            app.numberOfComponents.RoundFractionalValues = 'on';
            app.numberOfComponents.ValueChangedFcn = createCallbackFcn(app, @numberOfComponentsChanged, true);
            app.DatanormalizationPanel = uipanel(app.GeneralconfigurationTab);
            app.DatanormalizationPanel.Title = '5. Data normalization:';
            app.NormalizationmethodDropDownLabel = uilabel(app.DatanormalizationPanel);
            app.NormalizationmethodDropDownLabel.Text = 'Normalization method:';
            app.normalizationMethod = uidropdown(app.DatanormalizationPanel);
            app.normalizationMethod.Items = {'None', 'Across features', 'Across time', 'Across trials', 'Train set mean/std (across trials)'};
            app.normalizationMethod.ItemsData = {'0', '1', '2', '3', '4'};
            app.normalizationMethod.ValueChangedFcn = createCallbackFcn(app, @normalizationMethodChanged, true);
            app.normalizationMethod.Value = '4';
            app.ElectrodeselectPanel = uipanel(app.GeneralconfigurationTab);
            app.ElectrodeselectPanel.Title = '6. Electrode selection:';
            app.IndexesLabel = uilabel(app.ElectrodeselectPanel);
            app.IndexesLabel.Text = 'Indexes:';
            app.selectedIndexes = uieditfield(app.ElectrodeselectPanel, 'text');
            app.selectedIndexes.ValueChangedFcn = createCallbackFcn(app, @selectedIndexesValueChanged, true);
            app.selectedIndexes.Placeholder = 'default: all electrodes selected';
            app.Typetheindexesoftheselectedelectrodeseg135791113Label = uilabel(app.ElectrodeselectPanel);
            app.Typetheindexesoftheselectedelectrodeseg135791113Label.Text = 'Type the indexes of the selected electrodes (e.g., 1 3 5 7 9 11 13)';
            app.TemporalgeneralizationTab = uitab(app.ConfigureAnalysisTabGroup);
            app.TemporalgeneralizationTab.Title = 'Temporal generalization';
            app.TemporalgeneralizationanalysisPanel = uipanel(app.TemporalgeneralizationTab);
            app.TemporalgeneralizationanalysisPanel.Title = 'Temporal generalization analysis:';
            app.TemporalGeneralization = uicheckbox(app.TemporalgeneralizationanalysisPanel);
            app.TemporalGeneralization.ValueChangedFcn = createCallbackFcn(app, @temporalGeneralizationChanged, true);
            app.TemporalGeneralization.Text = 'Compute temporal generalization matrix';
            app.ConfigurationPanel = uipanel(app.TemporalgeneralizationTab);
            app.ConfigurationPanel.Title = 'Configuration:';
            app.TrainTestwindowsPanel = uipanel(app.ConfigurationPanel);
            app.TrainTestwindowsPanel.Title = 'Train & Test windows:';
            app.msLabel_3 = uilabel(app.TrainTestwindowsPanel);
            app.msLabel_3.Text = 'ms';
            app.msLabel_4 = uilabel(app.TrainTestwindowsPanel);
            app.msLabel_4.Text = 'ms';
            app.TrainstarttimeLabel = uilabel(app.TrainTestwindowsPanel);
            app.TrainstarttimeLabel.Text = 'Train start time:';
            app.trainStartTimeField = uieditfield(app.TrainTestwindowsPanel, 'numeric');
            app.trainStartTimeField.RoundFractionalValues = 'on';
            app.trainStartTimeField.ValueChangedFcn = createCallbackFcn(app, @trainStartTimeFieldChanged, true);
            app.msLabel_5 = uilabel(app.TrainTestwindowsPanel);
            app.msLabel_5.Text = 'ms';
            app.TeststarttimeLabel = uilabel(app.TrainTestwindowsPanel);
            app.TeststarttimeLabel.Text = 'Test start time:';
            app.testStartTimeField = uieditfield(app.TrainTestwindowsPanel, 'numeric');
            app.testStartTimeField.RoundFractionalValues = 'on';
            app.testStartTimeField.ValueChangedFcn = createCallbackFcn(app, @testStartTimeFieldChanged, true);
            app.msLabel_6 = uilabel(app.TrainTestwindowsPanel);
            app.msLabel_6.Text = 'ms';
            app.TestfinaltimeLabel = uilabel(app.TrainTestwindowsPanel);
            app.TestfinaltimeLabel.Text = 'Test final time:';
            app.testFinalTimeField = uieditfield(app.TrainTestwindowsPanel, 'numeric');
            app.testFinalTimeField.RoundFractionalValues = 'on';
            app.testFinalTimeField.ValueChangedFcn = createCallbackFcn(app, @testFinalTimeFieldChanged, true);
            app.TrainfinaltimeLabel = uilabel(app.TrainTestwindowsPanel);
            app.TrainfinaltimeLabel.Text = 'Train final time:';
            app.trainFinalTimeField = uieditfield(app.TrainTestwindowsPanel, 'numeric');
            app.trainFinalTimeField.RoundFractionalValues = 'on';
            app.trainFinalTimeField.ValueChangedFcn = createCallbackFcn(app, @trainFinalTimeFieldChanged, true);
            app.trainTestStepSizeField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.trainTestStepSizeField.Limits = [1 Inf];
            app.trainTestStepSizeField.RoundFractionalValues = 'on';
            app.trainTestStepSizeField.ValueChangedFcn = createCallbackFcn(app, @trainTestStepSizeFieldChanged, true);
            app.trainTestStepSizeField.Value = 1;
            app.timepointsLabel_3 = uilabel(app.ConfigurationPanel);
            app.timepointsLabel_3.Text = 'timepoints';
            app.extractDiagonal = uicheckbox(app.ConfigurationPanel);
            app.extractDiagonal.ValueChangedFcn = createCallbackFcn(app, @extractDiagonalChanged, true);
            app.extractDiagonal.Text = 'Extract diagonal for time-resolved analysis';
            app.stepSizeLabel = uilabel(app.ConfigurationPanel);
            app.stepSizeLabel.Text = 'Step size:';
            app.ClassificationmodelTab = uitab(app.ConfigureAnalysisTabGroup);
            app.ClassificationmodelTab.Title = 'Classification model';
            app.CrossvalidationPanel = uipanel(app.ClassificationmodelTab);
            app.CrossvalidationPanel.Title = 'Cross-validation:';
            app.ProcedureDropDownLabel = uilabel(app.CrossvalidationPanel);
            app.ProcedureDropDownLabel.Text = 'Procedure:';
            app.CVProcedure = uidropdown(app.CrossvalidationPanel);
            app.CVProcedure.Items = {'k-fold', 'Leave-one-out (LOO)'};
            app.CVProcedure.ItemsData = {'kfold', 'loo'};
            app.CVProcedure.ValueChangedFcn = createCallbackFcn(app, @cvMethodChanged, true);
            app.CVProcedure.Value = 'kfold';
            app.nFolds = uispinner(app.CrossvalidationPanel);
            app.nFolds.Limits = [2 Inf];
            app.nFolds.RoundFractionalValues = 'on';
            app.nFolds.ValueChangedFcn = createCallbackFcn(app, @nFoldsChanged, true);
            app.nFolds.Value = 5;
            app.nFoldsLabel = uilabel(app.CrossvalidationPanel);
            app.nFoldsLabel.Text = 'Number of folds:';
            app.OutputPanel = uipanel(app.ClassificationmodelTab);
            app.OutputPanel.Title = 'Output:';
            app.meanAccuracy = uicheckbox(app.OutputPanel);
            app.meanAccuracy.Enable = 'off';
            app.meanAccuracy.Text = 'Mean accuracy';
            app.meanAccuracy.Value = true;
            app.areaUnderCurve = uicheckbox(app.OutputPanel);
            app.areaUnderCurve.ValueChangedFcn = createCallbackFcn(app, @areaUnderCurveChanged, true);
            app.areaUnderCurve.Text = 'Area under curve';
            app.confusionMatrix = uicheckbox(app.OutputPanel);
            app.confusionMatrix.ValueChangedFcn = createCallbackFcn(app, @confusionMatrixChanged, true);
            app.confusionMatrix.Text = 'Confusion matrix';
            app.weightsVector = uicheckbox(app.OutputPanel);
            app.weightsVector.ValueChangedFcn = createCallbackFcn(app, @weightsVectorChanged, true);
            app.weightsVector.Text = 'Weights vector';
            app.precision = uicheckbox(app.OutputPanel);
            app.precision.ValueChangedFcn = createCallbackFcn(app, @precisionChanged, true);
            app.precision.Text = 'Precision';
            app.recall = uicheckbox(app.OutputPanel);
            app.recall.ValueChangedFcn = createCallbackFcn(app, @recallChanged, true);
            app.recall.Text = 'Recall';
            app.f1Score = uicheckbox(app.OutputPanel);
            app.f1Score.ValueChangedFcn = createCallbackFcn(app, @f1ScoreChanged, true);
            app.f1Score.Text = 'F1-score';
            app.roc = uicheckbox(app.OutputPanel);
            app.roc.ValueChangedFcn = createCallbackFcn(app, @rocValueChanged, true);
            app.roc.Text = 'ROC';
            app.ParallelcomputationSwitchLabel = uilabel(app.ClassificationmodelTab);
            app.ParallelcomputationSwitchLabel.Text = 'Parallel computation:';
            app.parallelComputation = uiswitch(app.ClassificationmodelTab, 'slider');
            app.parallelComputation.ItemsData = {'0', '1'};
            app.parallelComputation.ValueChangedFcn = createCallbackFcn(app, @parallelComputationValueChanged, true);
            app.parallelComputation.Value = '1';
            app.parallelCompuitationLabel = uilabel(app.ClassificationmodelTab);
            app.parallelCompuitationLabel.Text = 'Parallel computing toolbox required';
            app.ModelconfigurationPanel = uipanel(app.ClassificationmodelTab);
            app.ModelconfigurationPanel.Title = 'Model configuration:';
            app.ClassificationmethodDropDownLabel = uilabel(app.ModelconfigurationPanel);
            app.ClassificationmethodDropDownLabel.Text = 'Classification method:';
            app.classificationMethod = uidropdown(app.ModelconfigurationPanel);
            app.classificationMethod.Items = {'Support vector machine (SVM)', 'Discriminant analysis (DA)'};
            app.classificationMethod.ItemsData = {'svm', 'da'};
            app.classificationMethod.ValueChangedFcn = createCallbackFcn(app, @classificationMethodChanged, true);
            app.classificationMethod.Value = 'svm';
            app.ClassificationkernelDropDownLabel = uilabel(app.ModelconfigurationPanel);
            app.ClassificationkernelDropDownLabel.Text = 'Classification kernel:';
            app.classificationKernel = uidropdown(app.ModelconfigurationPanel);
            app.classificationKernel.Items = {'Linear', 'Gaussian', 'Radial basis function (RBF)', 'Polynomial'};
            app.classificationKernel.ItemsData = {'linear', 'gaussian', 'rbf', 'polynomial'};
            app.classificationKernel.ValueChangedFcn = createCallbackFcn(app, @classificationKernelChanged, true);
            app.classificationKernel.Value = 'linear';
            app.ModeloptimizationPanel = uipanel(app.ClassificationmodelTab);
            app.ModeloptimizationPanel.Title = 'Model optimization';
            app.optimizeHyperparameters = uicheckbox(app.ModeloptimizationPanel);
            app.optimizeHyperparameters.ValueChangedFcn = createCallbackFcn(app, @optimizeHyperparametersChanged, true);
            app.optimizeHyperparameters.Text = 'Optimize model hyperparameters';
            app.DefaultconfigurationLabel = uilabel(app.ModeloptimizationPanel);
            app.DefaultconfigurationLabel.Text = 'Default configuration:';
            app.OptimizergridsearchLabel = uilabel(app.ModeloptimizationPanel);
            app.OptimizergridsearchLabel.Text = 'Optimizer: ''gridsearch''';
            app.ParamsBoxConstraintLabel = uilabel(app.ModeloptimizationPanel);
            app.ParamsBoxConstraintLabel.Text = 'Params: ''BoxConstraint''';
            app.nfolds5Label = uilabel(app.ModeloptimizationPanel);
            app.nfolds5Label.Text = 'nfolds: 5';
            app.permuteLabels = uicheckbox(app.ClassificationmodelTab);
            app.permuteLabels.ValueChangedFcn = createCallbackFcn(app, @permuteLabelsChanged, true);
            app.permuteLabels.Text = 'Permute labels';
            app.StatisticsTab = uitab(app.ConfigureAnalysisTabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.PermutedmapsPanel = uipanel(app.StatisticsTab);
            app.PermutedmapsPanel.Title = 'Permuted maps:';
            app.NumberofpermutedmapspersubjectLabel = uilabel(app.PermutedmapsPanel);
            app.NumberofpermutedmapspersubjectLabel.Text = 'Number of permuted maps (per subject) :';
            app.PermutedMapsSubject = uispinner(app.PermutedmapsPanel);
            app.PermutedMapsSubject.Limits = [10 1000];
            app.PermutedMapsSubject.RoundFractionalValues = 'on';
            app.PermutedMapsSubject.ValueChangedFcn = createCallbackFcn(app, @permutedMapsSubjectChanged, true);
            app.PermutedMapsSubject.Value = 100;
            app.NumberofpermutedmapsgrouplevelSpinnerLabel = uilabel(app.PermutedmapsPanel);
            app.NumberofpermutedmapsgrouplevelSpinnerLabel.Text = 'Number of permuted maps (group level) :';
            app.PermutedMapsGroup = uispinner(app.PermutedmapsPanel);
            app.PermutedMapsGroup.Limits = [10 1000000];
            app.PermutedMapsGroup.RoundFractionalValues = 'on';
            app.PermutedMapsGroup.ValueChangedFcn = createCallbackFcn(app, @permutedMapsGroupChanged, true);
            app.PermutedMapsGroup.Value = 100000;
            app.plotNullDistribution = uicheckbox(app.PermutedmapsPanel);
            app.plotNullDistribution.ValueChangedFcn = createCallbackFcn(app, @plotNullDistributionChanged, true);
            app.plotNullDistribution.Text = 'Plot null distribution';
            app.ClusterbasedpermutationanalysisPanel = uipanel(app.StatisticsTab);
            app.ClusterbasedpermutationanalysisPanel.Title = 'Cluster-based permutation analysis:';
            app.permutationAnalysisFlag = uicheckbox(app.ClusterbasedpermutationanalysisPanel);
            app.permutationAnalysisFlag.ValueChangedFcn = createCallbackFcn(app, @permutationAnalysisFlagChanged, true);
            app.permutationAnalysisFlag.Text = 'Compute cluster-based permutation analysis';
            app.SelectanalysisDropDownLabel = uilabel(app.ClusterbasedpermutationanalysisPanel);
            app.SelectanalysisDropDownLabel.Text = 'Select analysis:';
            app.SelectanalysisDropDown = uidropdown(app.ClusterbasedpermutationanalysisPanel);
            app.SelectanalysisDropDown.Items = {'Above chance clusters - Right tail', 'Below chance clusters - Left tail', 'Above and below chance - Two tails'};
            app.SelectanalysisDropDown.ItemsData = {'1', '-1', '2'};
            app.SelectanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @numberOfTailsChanged, true);
            app.SelectanalysisDropDown.Value = '2';
            app.PercentilethresholdsPanel = uipanel(app.StatisticsTab);
            app.PercentilethresholdsPanel.Title = 'Percentile thresholds:';
            app.GroupsignificancelevelEditFieldLabel = uilabel(app.PercentilethresholdsPanel);
            app.GroupsignificancelevelEditFieldLabel.Text = 'Group significance level:';
            app.GroupSignificanceLevel = uieditfield(app.PercentilethresholdsPanel, 'numeric');
            app.GroupSignificanceLevel.Limits = [0.1 99.9999];
            app.GroupSignificanceLevel.ValueChangedFcn = createCallbackFcn(app, @groupSignificanceLevelChanged, true);
            app.GroupSignificanceLevel.Value = 99.9;
            app.ClustersignificancelevelEditFieldLabel = uilabel(app.PercentilethresholdsPanel);
            app.ClustersignificancelevelEditFieldLabel.Text = 'Cluster significance level:';
            app.ClusterSignificanceLevel = uieditfield(app.PercentilethresholdsPanel, 'numeric');
            app.ClusterSignificanceLevel.Limits = [0.1 99.9999];
            app.ClusterSignificanceLevel.ValueChangedFcn = createCallbackFcn(app, @clusterSignificanceLevelChanged, true);
            app.ClusterSignificanceLevel.Value = 99.9;
            app.Label_2 = uilabel(app.PercentilethresholdsPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.PercentilethresholdsPanel);
            app.Label_3.Text = '%';
            app.SlidingfilterTab = uitab(app.ConfigureAnalysisTabGroup);
            app.SlidingfilterTab.Title = 'Sliding filter';
            app.SlidingfilteranalysisPanel = uipanel(app.SlidingfilterTab);
            app.SlidingfilteranalysisPanel.Title = 'Sliding-filter analysis';
            app.warning = uilabel(app.SlidingfilteranalysisPanel);
            app.warning.Text = 'Warning: This analysis is slow and requires EEGLAB installed and a huge amount of free space in your hard drive. ';
            app.AlternativelocationforfilteredfilesEditFieldLabel = uilabel(app.SlidingfilteranalysisPanel);
            app.AlternativelocationforfilteredfilesEditFieldLabel.Text = 'Alternative location for filtered files:';
            app.AlternativeLocationFolder = uieditfield(app.SlidingfilteranalysisPanel, 'text');
            app.AlternativeLocationFolder.Value = 'External drive preferred (huge amount of free space needed)';
            app.sfFilesLocation = uibutton(app.SlidingfilteranalysisPanel, 'push');
            app.sfFilesLocation.ButtonPushedFcn = createCallbackFcn(app, @sfFilesLocationPushed, true);
            app.sfFilesLocation.Text = '...';
            app.sfAnalysisFlag = uicheckbox(app.SlidingfilteranalysisPanel);
            app.sfAnalysisFlag.ValueChangedFcn = createCallbackFcn(app, @sfAnalysisFlagChanged, true);
            app.sfAnalysisFlag.Text = 'Compute sliding filter analysis';
            app.PerformancemetricDropDownLabel = uilabel(app.SlidingfilteranalysisPanel);
            app.PerformancemetricDropDownLabel.Text = 'Performance metric:';
            app.sfPerformanceMetric = uidropdown(app.SlidingfilteranalysisPanel);
            app.sfPerformanceMetric.Items = {'Mean accuracy', 'Area Under Curve'};
            app.sfPerformanceMetric.ItemsData = {'acc', 'auc'};
            app.sfPerformanceMetric.ValueChangedFcn = createCallbackFcn(app, @sfPerformanceMetricChanged, true);
            app.sfPerformanceMetric.Value = 'auc';
            app.FrequencyconfigurationPanel = uipanel(app.SlidingfilterTab);
            app.FrequencyconfigurationPanel.Title = 'Frequency configuration:';
            app.SuperiorlimitSpinnerLabel = uilabel(app.FrequencyconfigurationPanel);
            app.SuperiorlimitSpinnerLabel.Text = 'Superior limit:';
            app.superiorFrequencyLimit = uispinner(app.FrequencyconfigurationPanel);
            app.superiorFrequencyLimit.Limits = [0 Inf];
            app.superiorFrequencyLimit.ValueChangedFcn = createCallbackFcn(app, @superiorFrequencyLimitChanged, true);
            app.superiorFrequencyLimit.Value = 40;
            app.InferiorlimitSpinnerLabel = uilabel(app.FrequencyconfigurationPanel);
            app.InferiorlimitSpinnerLabel.Text = 'Inferior limit:';
            app.inferiorFrequencyLimit = uispinner(app.FrequencyconfigurationPanel);
            app.inferiorFrequencyLimit.Limits = [0 Inf];
            app.inferiorFrequencyLimit.ValueChangedFcn = createCallbackFcn(app, @inferiorFrequencyLimitChanged, true);
            app.HzLabel_2 = uilabel(app.FrequencyconfigurationPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.FrequencyconfigurationPanel);
            app.HzLabel_3.Text = 'Hz';
            app.NoofstepsLabel = uilabel(app.FrequencyconfigurationPanel);
            app.NoofstepsLabel.Text = 'No. of steps:';
            app.nFrequencySteps = uispinner(app.FrequencyconfigurationPanel);
            app.nFrequencySteps.Limits = [1 Inf];
            app.nFrequencySteps.RoundFractionalValues = 'on';
            app.nFrequencySteps.ValueChangedFcn = createCallbackFcn(app, @nFreqStepsChanged, true);
            app.nFrequencySteps.Value = 40;
            app.SpacingscaleLabel = uilabel(app.FrequencyconfigurationPanel);
            app.SpacingscaleLabel.Text = 'Spacing scale:';
            app.spacingScale = uidropdown(app.FrequencyconfigurationPanel);
            app.spacingScale.Items = {'Linear', 'Log'};
            app.spacingScale.ItemsData = {'lin', 'log'};
            app.spacingScale.ValueChangedFcn = createCallbackFcn(app, @spacingScaleChanged, true);
            app.spacingScale.Value = 'lin';
            app.FilteroptionPanel = uipanel(app.SlidingfilterTab);
            app.FilteroptionPanel.Title = 'Filter option:';
            app.FilterorderevenSpinnerLabel = uilabel(app.FilteroptionPanel);
            app.FilterorderevenSpinnerLabel.Text = 'Filter order (even):';
            app.filterOrder = uispinner(app.FilteroptionPanel);
            app.filterOrder.Limits = [1 Inf];
            app.filterOrder.RoundFractionalValues = 'on';
            app.filterOrder.ValueChangedFcn = createCallbackFcn(app, @filterOrderChanged, true);
            app.filterOrder.Value = 1408;
            app.FilterbandwidthHzSpinnerLabel = uilabel(app.FilteroptionPanel);
            app.FilterbandwidthHzSpinnerLabel.Text = 'Filter bandwidth (Hz):';
            app.filterBW = uispinner(app.FilteroptionPanel);
            app.filterBW.Limits = [0.1 Inf];
            app.filterBW.ValueChangedFcn = createCallbackFcn(app, @filterBWChanged, true);
            app.filterBW.Value = 2;
            app.FiltertypeDropDownLabel = uilabel(app.FilteroptionPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type:';
            app.filterType = uidropdown(app.FilteroptionPanel);
            app.filterType.Items = {'Bandstop', 'Bandpass'};
            app.filterType.ItemsData = {'bandstop', 'bandpass'};
            app.filterType.ValueChangedFcn = createCallbackFcn(app, @filterTypeChanged, true);
            app.filterType.Value = 'bandstop';
            app.WindowtypeDropDownLabel = uilabel(app.FilteroptionPanel);
            app.WindowtypeDropDownLabel.Text = 'Window type:';
            app.filterWindowType = uidropdown(app.FilteroptionPanel);
            app.filterWindowType.Items = {'Rectangular (-21dB)', 'Hann (-44dB)', 'Hamming (-53dB)', 'Blackman (-74dB)', 'Kaiser (-60dB)'};
            app.filterWindowType.ItemsData = {'rectangular', 'hann', 'hamming', 'blackman', 'kaiser'};
            app.filterWindowType.ValueChangedFcn = createCallbackFcn(app, @filterWindowTypeChanged, true);
            app.filterWindowType.Value = 'rectangular';
            app.plotFerqResponse = uibutton(app.FilteroptionPanel, 'push');
            app.plotFerqResponse.ButtonPushedFcn = createCallbackFcn(app, @plotFerquencyResponse, true);
            app.plotFerqResponse.Icon = 'graph_.png';
            app.plotFerqResponse.Text = {'Plot ferquency'; ' response'};
            app.ConfigureanalysisLabel = uilabel(app.StudyconfigurationUIFigure);
            app.ConfigureanalysisLabel.Text = 'Configure analysis';
            app.AnalysisfolderPanel = uipanel(app.StudyconfigurationUIFigure);
            app.AnalysisfolderPanel.Title = 'Analysis folder:';
            app.analysisFolder = uieditfield(app.AnalysisfolderPanel, 'text');
            app.analysisFolder.Editable = 'off';
            app.startAnalysisButton = uibutton(app.AnalysisfolderPanel, 'push');
            app.startAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @startAnalysisPushed, true);
            app.startAnalysisButton.Icon = 'play_.png';
            app.startAnalysisButton.Enable = 'off';
            app.startAnalysisButton.Text = 'Start analysis';
            app.plotResultsButton = uibutton(app.AnalysisfolderPanel, 'push');
            app.plotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResultsButtonPushed, true);
            app.plotResultsButton.Icon = 'graph_.png';
            app.plotResultsButton.Text = 'Plot result utility';
            app.ActionPanel = uipanel(app.StudyconfigurationUIFigure);
            app.versionLabel = uilabel(app.ActionPanel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.ActionPanel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.ActionsPanel = uipanel(app.ActionPanel);
            app.ActionsPanel.Title = 'Actions:';
            app.CreatenewstudyButton = uibutton(app.ActionsPanel, 'push');
            app.CreatenewstudyButton.ButtonPushedFcn = createCallbackFcn(app, @createNewStudy, true);
            app.CreatenewstudyButton.Icon = 'new_.png';
            app.CreatenewstudyButton.Text = 'Create new study';
            app.OpenstudyButton = uibutton(app.ActionsPanel, 'push');
            app.OpenstudyButton.ButtonPushedFcn = createCallbackFcn(app, @openPreviousStudy, true);
            app.OpenstudyButton.Icon = 'open_.png';
            app.OpenstudyButton.Text = 'Open study';
            app.SaveButton = uibutton(app.ActionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.SaveButton.Icon = 'save_.png';
            app.SaveButton.Text = 'Save';
            app.SaveasButton = uibutton(app.ActionsPanel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @saveAsButtonPushed, true);
            app.SaveasButton.Icon = 'duplicate_.png';
            app.SaveasButton.Text = 'Save as';
            app.Button = uibutton(app.StudyconfigurationUIFigure, 'push');
            app.Button.Icon = 'folder_.png';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.StudyconfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = studyView(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StudyconfigurationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StudyconfigurationUIFigure)
        end
    end
end